classdef EscherAppMaster < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EscherMasterFig                 matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SettingsTab                     matlab.ui.container.Tab
        uipanel6                        matlab.ui.container.Panel
        OpenScreenBtn                   matlab.ui.control.Button
        RefreshStBy                     matlab.ui.control.Button
        OneScreenMode_ck                matlab.ui.control.CheckBox
        oculusCk                        matlab.ui.control.CheckBox
        SetstandbyluminanceEditFieldLabel  matlab.ui.control.Label
        StandbyL                        matlab.ui.control.NumericEditField
        LabelLumi_1                     matlab.ui.control.Label
        uipanel4                        matlab.ui.container.Panel
        ScreendistancecmLabel           matlab.ui.control.Label
        ScreenDistance                  matlab.ui.control.NumericEditField
        WidthpixelsEditFieldLabel       matlab.ui.control.Label
        WidthPix                        matlab.ui.control.NumericEditField
        HeightpixelsEditFieldLabel      matlab.ui.control.Label
        HeightPix                       matlab.ui.control.NumericEditField
        WidthcmLabel                    matlab.ui.control.Label
        ScreenWidth                     matlab.ui.control.NumericEditField
        HeightcmEditFieldLabel          matlab.ui.control.Label
        ScreenHeight                    matlab.ui.control.NumericEditField
        AngularsizeofscreenEditFieldLabel  matlab.ui.control.Label
        ScreenSizeAngle                 matlab.ui.control.EditField
        SynchronizationPanel            matlab.ui.container.Panel
        ard_ck                          matlab.ui.control.CheckBox
        HeightpxEditFieldLabel          matlab.ui.control.Label
        hhTxt                           matlab.ui.control.NumericEditField
        CueWidthpxEditFieldLabel        matlab.ui.control.Label
        wwTxt                           matlab.ui.control.NumericEditField
        calblack_btn                    matlab.ui.control.Button
        calwhite_btn                    matlab.ui.control.Button
        CuepositionPanel                matlab.ui.container.Panel
        ButtonGroup_2                   matlab.ui.container.ButtonGroup
        CueTopRB                        matlab.ui.control.RadioButton
        CueBotRB                        matlab.ui.control.RadioButton
        ButtonGroup_3                   matlab.ui.container.ButtonGroup
        CueLeftRB                       matlab.ui.control.RadioButton
        CueRightRB                      matlab.ui.control.RadioButton
        DurationofcuemsEditFieldLabel   matlab.ui.control.Label
        optDtrTimeTxt                   matlab.ui.control.NumericEditField
        UDPConnectionPanel              matlab.ui.container.Panel
        StartUdpBtn                     matlab.ui.control.Button
        SlaveIPLabel                    matlab.ui.control.Label
        SlaveIp                         matlab.ui.control.EditField
        PortEditFieldLabel              matlab.ui.control.Label
        SlavePort                       matlab.ui.control.NumericEditField
        MasterIPLabel                   matlab.ui.control.Label
        MasterIp                        matlab.ui.control.EditField
        PortEditFieldLabel_2            matlab.ui.control.Label
        MasterPort                      matlab.ui.control.NumericEditField
        GetMasterIPandMACBtn            matlab.ui.control.Button
        StatusEditFieldLabel_2          matlab.ui.control.Label
        ConnectionStatus                matlab.ui.control.EditField
        MACLabel_3                      matlab.ui.control.Label
        MasterMac                       matlab.ui.control.EditField
        MACLabel_2                      matlab.ui.control.Label
        SlaveMac                        matlab.ui.control.EditField
        TimeouttimesEditFieldLabel      matlab.ui.control.Label
        TimeoutTime                     matlab.ui.control.NumericEditField
        ResendScreenParams              matlab.ui.control.Button
        LoadScreenInformationPanel      matlab.ui.container.Panel
        LoadScreenFileButton            matlab.ui.control.Button
        ScreennameEditFieldLabel        matlab.ui.control.Label
        ScreenNameTxt                   matlab.ui.control.EditField
        ScreenTransferFormulaLabel      matlab.ui.control.Label
        ScreenFuncTxt                   matlab.ui.control.EditField
        NotesLabel                      matlab.ui.control.Label
        ScreenNotesTxt                  matlab.ui.control.EditField
        SaveScreenFileButton            matlab.ui.control.Button
        luminanceLabel                  matlab.ui.control.Label
        CalModeRBGroup                  matlab.ui.container.ButtonGroup
        UncalibratedButton              matlab.ui.control.RadioButton
        CalibratedButton                matlab.ui.control.RadioButton
        StimulationTab                  matlab.ui.container.Tab
        FlashPanel                      matlab.ui.container.Panel
        BaselineluminanceLabel          matlab.ui.control.Label
        FSbaselineL                     matlab.ui.control.NumericEditField
        FlashluminanceLabel             matlab.ui.control.Label
        FSflashL                        matlab.ui.control.NumericEditField
        RepetitionsLabel_2              matlab.ui.control.Label
        FlashRepetitions                matlab.ui.control.NumericEditField
        PeriodsEditFieldLabel           matlab.ui.control.Label
        FlashPeriod                     matlab.ui.control.NumericEditField
        LabelLumi_2                     matlab.ui.control.Label
        LabelLumi_3                     matlab.ui.control.Label
        FlashdurationsLabel             matlab.ui.control.Label
        FlashDuration                   matlab.ui.control.NumericEditField
        FSbaselineLEsch                 matlab.ui.control.NumericEditField
        LabelLumiEsch_2                 matlab.ui.control.Label
        FSflashLEsch                    matlab.ui.control.NumericEditField
        LabelLumiEsch_3                 matlab.ui.control.Label
        FastFlashStimulusSelectionPanel  matlab.ui.container.Panel
        FlashFastTable                  matlab.ui.control.Table
        noftotstimLabel                 matlab.ui.control.Label
        FlashFastTotN                   matlab.ui.control.NumericEditField
        MinLEditFieldLabel              matlab.ui.control.Label
        FlashFastMinL                   matlab.ui.control.NumericEditField
        MaxLEditFieldLabel              matlab.ui.control.Label
        FlashFastMaxL                   matlab.ui.control.NumericEditField
        logspacedFlashCk                matlab.ui.control.CheckBox
        includezeroFlashCk              matlab.ui.control.CheckBox
        generatestimuliFlashBtn         matlab.ui.control.Button
        savetableButton                 matlab.ui.control.Button
        FlashFastLoadTable              matlab.ui.control.Button
        ChoosestimEditFieldLabel        matlab.ui.control.Label
        FlashFastN                      matlab.ui.control.NumericEditField
        FSFlashGo                       matlab.ui.control.Button
        uipanel14                       matlab.ui.container.Panel
        InitialdelaysLabel              matlab.ui.control.Label
        initialDelay                    matlab.ui.control.NumericEditField
        ContrastStimulationPanel        matlab.ui.container.Panel
        LabelLumi_4                     matlab.ui.control.Label
        BlackluminanceLabel             matlab.ui.control.Label
        CheckLumiB                      matlab.ui.control.NumericEditField
        LabelLumi_5                     matlab.ui.control.Label
        WhiteluminanceLabel             matlab.ui.control.Label
        CheckLumiW                      matlab.ui.control.NumericEditField
        LabelLumi_6                     matlab.ui.control.Label
        MeanluminanceLabel              matlab.ui.control.Label
        CheckLumiMean                   matlab.ui.control.NumericEditField
        ContrastLabel                   matlab.ui.control.Label
        CheckContrast                   matlab.ui.control.NumericEditField
        CheckerboardPanel               matlab.ui.container.Panel
        CheckGo                         matlab.ui.control.Button
        RepetitionsEditField_2Label     matlab.ui.control.Label
        CheckRepetitions                matlab.ui.control.NumericEditField
        PeriodsEditField_2Label         matlab.ui.control.Label
        CheckPeriod                     matlab.ui.control.NumericEditField
        GratingsPanel                   matlab.ui.container.Panel
        SliderOrientation               matlab.ui.control.Slider
        GoGrid                          matlab.ui.control.Button
        DrawMask                        matlab.ui.control.CheckBox
        ButtonGroup                     matlab.ui.container.ButtonGroup
        SineGrid                        matlab.ui.control.RadioButton
        SquareGrid                      matlab.ui.control.RadioButton
        GridorientationdegEditFieldLabel  matlab.ui.control.Label
        GridOrientation                 matlab.ui.control.NumericEditField
        RepetitionsEditFieldLabel       matlab.ui.control.Label
        GridRepetitions                 matlab.ui.control.NumericEditField
        BaselineGratingPostEditFieldLabel  matlab.ui.control.Label
        PreGridDuration                 matlab.ui.control.NumericEditField
        GridDuration                    matlab.ui.control.NumericEditField
        PostGridDuration                matlab.ui.control.NumericEditField
        DriftingspeedcyclessecEditFieldLabel  matlab.ui.control.Label
        DriftingSpeed_txt               matlab.ui.control.NumericEditField
        SizegaussianmaskpixEditFieldLabel  matlab.ui.control.Label
        GaussSize_txt                   matlab.ui.control.NumericEditField
        FastOrientationStimulusSelectionPanel  matlab.ui.container.Panel
        generatestimuliOrientBtn        matlab.ui.control.Button
        OrientFastSaveTable             matlab.ui.control.Button
        OrientFastLoadTable             matlab.ui.control.Button
        noftotstimLabel_3               matlab.ui.control.Label
        OrientFastTotN                  matlab.ui.control.NumericEditField
        StartAngleEditFieldLabel        matlab.ui.control.Label
        OrientFastMinA                  matlab.ui.control.NumericEditField
        EndAngleEditFieldLabel          matlab.ui.control.Label
        OrientFastMaxA                  matlab.ui.control.NumericEditField
        OrientFastTable                 matlab.ui.control.Table
        ChoosestimEditFieldLabel_3      matlab.ui.control.Label
        OrientFastN                     matlab.ui.control.NumericEditField
        CheckLumiBEsch                  matlab.ui.control.NumericEditField
        CheckLumiWEsch                  matlab.ui.control.NumericEditField
        CheckLumiMeanEsch               matlab.ui.control.NumericEditField
        LabelLumiEsch_4                 matlab.ui.control.Label
        LabelLumiEsch_5                 matlab.ui.control.Label
        LabelLumiEsch_6                 matlab.ui.control.Label
        FastContrastStimulusSelectionPanel  matlab.ui.container.Panel
        CheckFastTable                  matlab.ui.control.Table
        ChoosestimEditFieldLabel_2      matlab.ui.control.Label
        CheckFastN                      matlab.ui.control.NumericEditField
        CheckFastSaveTable              matlab.ui.control.Button
        CheckFastLoadTable              matlab.ui.control.Button
        generatestimuliCheckBtn         matlab.ui.control.Button
        logspacedCheckCk                matlab.ui.control.CheckBox
        includezeroCheckCk              matlab.ui.control.CheckBox
        MinLEditFieldLabel_2            matlab.ui.control.Label
        CheckFastMinC                   matlab.ui.control.NumericEditField
        MaxLEditFieldLabel_2            matlab.ui.control.Label
        CheckFastMaxC                   matlab.ui.control.NumericEditField
        noftotstimLabel_2               matlab.ui.control.Label
        CheckFastTotN                   matlab.ui.control.NumericEditField
        MeanLEditFieldLabel             matlab.ui.control.Label
        CheckFastMeanL                  matlab.ui.control.NumericEditField
        FrequencycyclesDegLabel         matlab.ui.control.Label
        spatialFrequency                matlab.ui.control.NumericEditField
        ExperimentlistTab               matlab.ui.container.Tab
        ExperimentTable                 matlab.ui.control.Table
        RandomizeexperimentPanel        matlab.ui.container.Panel
        ExpListFlashCk                  matlab.ui.control.CheckBox
        ExpListCheckCk                  matlab.ui.control.CheckBox
        ExpListGratingsOrientCk         matlab.ui.control.CheckBox
        RandomizeButton                 matlab.ui.control.Button
        NumberofsessionsEditFieldLabel  matlab.ui.control.Label
        ExpListSessionN                 matlab.ui.control.NumericEditField
        ExpListGratingsContrastCk       matlab.ui.control.CheckBox
        ExpListGo                       matlab.ui.control.Button
        ExpListAddRows                  matlab.ui.control.Button
        ExpListDeleteRows               matlab.ui.control.Button
        ExpMoveUp                       matlab.ui.control.Button
        ExpMoveDown                     matlab.ui.control.Button
        CopytableButton                 matlab.ui.control.Button
        CurrentrunnEditFieldLabel       matlab.ui.control.Label
        ExpListCurrentNum               matlab.ui.control.NumericEditField
        RepeatStimBtn                   matlab.ui.control.Button
        NoffirstexpEditFieldLabel       matlab.ui.control.Label
        ExpListFirstNum                 matlab.ui.control.NumericEditField
        PupilWhileStimulating           matlab.ui.control.CheckBox
        PupilTab                        matlab.ui.container.Tab
        CameraPanel                     matlab.ui.container.Panel
        RefreshDevicesButton            matlab.ui.control.Button
        CameraDropDownLabel             matlab.ui.control.Label
        CameraDropDown                  matlab.ui.control.DropDown
        ImageSizeDropDownLabel          matlab.ui.control.Label
        ImageSizeDropDown               matlab.ui.control.DropDown
        CameraUnconnectedLampLabel      matlab.ui.control.Label
        CameraUnconnectedLamp           matlab.ui.control.Lamp
        PreviewPanel                    matlab.ui.container.Panel
        NumberofPointsinPreviewGraphEditFieldLabel  matlab.ui.control.Label
        NumberofPointsinPreviewGraphEditField  matlab.ui.control.NumericEditField
        RatefpsEditFieldLabel           matlab.ui.control.Label
        RatefpsEditField                matlab.ui.control.NumericEditField
        StartPreviewButton              matlab.ui.control.Button
        skipPupilPrevCk                 matlab.ui.control.CheckBox
        ResetAxesButton                 matlab.ui.control.Button
        Image1UIAxes                    matlab.ui.control.UIAxes
        Image2UIAxes                    matlab.ui.control.UIAxes
        GraphUIAxes                     matlab.ui.control.UIAxes
        ThresholdSliderLabel            matlab.ui.control.Label
        ThresholdSlider                 matlab.ui.control.Slider
        AcquisitionPanel                matlab.ui.container.Panel
        DurationsEditFieldLabel         matlab.ui.control.Label
        DurationsEditField              matlab.ui.control.NumericEditField
        PupilStartAcquisitionButton     matlab.ui.control.Button
        PreviewWhileAcquiringCheckBox   matlab.ui.control.CheckBox
        SaveFolderEditFieldLabel        matlab.ui.control.Label
        SaveFolderEditField             matlab.ui.control.EditField
        FilenEditFieldLabel             matlab.ui.control.Label
        OutputFileN                     matlab.ui.control.NumericEditField
        StatusEditFieldLabel            matlab.ui.control.Label
        acqStatusTxt                    matlab.ui.control.EditField
        skipPupilAcqCk                  matlab.ui.control.CheckBox
        acqLamp                         matlab.ui.control.Lamp
        BusyStatusLampLabel             matlab.ui.control.Label
        BusyStatusLamp                  matlab.ui.control.Lamp
        DeBusyButton                    matlab.ui.control.Button
        UDPConnectionLampLabel          matlab.ui.control.Label
        UDPConnectionLamp               matlab.ui.control.Lamp
    end

    
    properties (Access = public)
        %         For the most common values (dimension of the screen, black and
        %         white values, ...)
        
        %         ComHandle % Former synchronization TTL signal
        w
        screenRect
        ifi
        white
        black
        ScreenBitDepth % is either 'uint8' or 'uint16'
        
        %         For UDP connection:
        UdpObj % the actual UDP object
        UdpTimeoutTimer % the timer for making the UDP connection time out
        UdpConnectionFlag % When this is 1, this app works as master
        
        %         This is the structure containing all the variables for the pupil
        %         recording.
        PupilStruct
        
        ScreenOpenFlag % is 1 when the screen is open, it's 0 when it's not open
        
        %         folder of current escher app
        escherAppFolder
        PathSeparator
        %         For logs
        runNumberFile % the log file where the run number is stored.
        logDir % the directory of log files
        runNumber % The run number, progressive number of the next stimulation.
        CurrentLogStruct % structure containing .T (a table) and .logName, the name for current log.
        
        %         Label for luminance of the vector of objects
        LabeLumiVector
        
        %         Store the indices of selected cells of the experiment list
        ExpListSelectedIndices
        %         The colors of the experiment table. First is the background, then
        %         the current exp, then the running exp, then the completed exp.
        ExpListColors
        
        %         This is the index of current experiment on the Experiment List
        %         table. Not to be confused with ExpListCurrentNum (which is the
        %         number assigned to the current experiment).
        ExpListCurrentIdx
        
    end
    
    methods (Access = public)        
        function saveParams(app, filename)
            paramsStruct = struct;
            
            propList = string(properties(app)); % get all the properies of the main app as string vector
            propListLogic = false(size(propList)); % initialize a logic vector, that will be used to tell apart checkboxes and edit fields from the rest
            
            for i=1:length(propList)
                if isobject(app.(propList(i))) && isprop(app.(propList(i)),'Type') % only isprop is not enough
                    if strcmp(app.(propList(i)).Type,'uinumericeditfield') || strcmp(app.(propList(i)).Type,'uicheckbox') || (strcmp(app.(propList(i)).Type,'uieditfield') && app.(propList(i)).Editable==1)
                        % logic 1 for all properties that are either
                        % numeric edit fields or checkboxes. Also added
                        % edit field (non numeric) but only if they are
                        % editable
                        propListLogic(i) = true;
                    end
                end
            end
            
            % paramsTable is 2-column table. First column is for names,
            % second is for values
            paramsTable = table('size', [sum(propListLogic), 2],'VariableTypes', {'string', 'cell'}, 'VariableNames', {'Name', 'Value'});
            paramsTable.Name = propList(propListLogic); % list of only checkboxes and editable textboxes
            
            % Extract all the names, values and callbacks of the checkboxes and editable textboxes of the main App
            for i=1:height(paramsTable)
                paramsTable.Value{i} = app.(paramsTable.Name(i)).Value;
            end
            
            paramsStruct.Table = paramsTable;
            
            save(filename, 'paramsStruct')
        end
        
        function loadParams(app,filename)
            %             This function load the parameters saved in a .mat file. The
            %             parameters are in a structure called paramsStruct.
            
            paramsStruct = [];
            
            %             All parameters from the gui are saved separately in a table,
            %             called paramsTable.
            
            open(filename) % from the filename location, a variable paramsStruct will be loaded
            for i = 1 : height(paramsStruct.paramsTable)
                %                 i
                %                 if i==24
                %                     disp('eccoci')
                %                 end
                app.(paramsStruct.paramsTable.Name(i)).Value = paramsStruct.paramsTable.Value{i};
                if ~isempty(app.(paramsStruct.paramsTable.Name(i)).ValueChangedFcn)
                    try
                        app.(paramsStruct.paramsTable.Name(i)).ValueChangedFcn(app.(paramsStruct.paramsTable.Name(i)),[])
                    catch ME
                        warning('off','backtrace')
                        warning(strcat("Error while executing ", paramsStruct.paramsTable.Name(i), "'s Callback"))
                        warning(ME.message)
                        disp(newline)
                    end
                end
            end
            
            warning('on','backtrace')
            % load handel
            % sound(y,Fs)
            disp('params loaded!')
        end
        
        function OpenScreen(app, background)
            %[w, ScreenRect, white, black, gray, ifi] function OpenScreen
            % function OpenScreen
            % ___________________________________________________________________
            %
            % Open and initialize the stimulus screen, This should run only once as the
            % program starts up
            
            % HISTORY
            %  08/01/2011 gmr    adapted for Esher
            
            %             This should be 1 for general use. Synchronization is less
            %             reliable but the execution is more robust
            Screen('Preference', 'SkipSyncTests', 1)
            
            try
               	% This script calls Psychtoolbox commands available only in OpenGL-based
               	% versions of the Psychtoolbox. (So far, the OS X Psychtoolbox is the
               	% only OpenGL-base Psychtoolbox.)  The Psychtoolbox command AssertPsychOpenGL will issue
               	% an error message if someone tries to execute this script on a computer without
               	% an OpenGL Psychtoolbox
                AssertOpenGL;
               	
               	% Get the list of screens and choose the one with the highest screen number.
               	% Screen 0 is, by definition, the display with the menu bar. Often when
               	% two monitors are connected the one without the menu bar is used as
               	% the stimulus display.  Chosing the display with the highest dislay number is
               	% a best guess about where you want the stimulus displayed.
               	screens = Screen('Screens');
                if length(screens)==1 && ~app.OneScreenMode_ck.Value
%                     If there is only one screen and OneScreenMode is not
%                     checked, then no screen is opened.
                    return
                end
               	screenNumber = max(screens);
                
                % Find the color values which correspond to white and black: Usually
               	% black is always 0 and white 255, but this rule is not true if one of
               	% the high precision framebuffer modes is enabled via the
               	% PsychImaging() commmand, so we query the true values via the
               	% functions WhiteIndex and BlackIndex:
               	app.white = WhiteIndex(screenNumber);
               	app.black = BlackIndex(screenNumber);
                

%                 ENRICO. What is the purpose of this part? I commented it
%                 out and removed app.gray property.
%                 % Round gray to integral number, to avoid roundoff artifacts with some
%                 % graphics cards:
%                	app.gray = round((app.white+app.black)/2);
%                 % This makes sure that on floating point framebuffers we still get a
%                 % well defined gray. It isn't strictly necessary in this demo:
%                 if app.gray == app.white
%                   		app.gray = app.white / 2;
%                 end
%                 
%                 % Contrast 'inc'rement range for given white and gray values:
%                	inc = app.white-app.gray;
                
                
                % Open a double buffered fullscreen window and set default background
               	% color to gray:
               	[app.w, app.screenRect] = Screen('OpenWindow',screenNumber, background*app.white);
                
                % if drawmask
                % Enable alpha blending for proper combination of the gaussian aperture
                % with the drifting sine grating:
                Screen('BlendFunction', app.w, GL_SRC_ALPHA, GL_ONE_MINUS_SRC_ALPHA);
                % end
                
                % Query maximum useable priorityLevel on this system:
               	priorityLevel = MaxPriority(app.w); %#ok<NASGU>
                
                % We don't use Priority() in order to not accidentally overload older
                % machines that can't handle a redraw every 40 ms. If your machine is
                % fast enough, uncomment this to get more accurate timing.
                % Priority(priorityLevel);
                
                
                %                NEW Enrico 20190524
                %                According to the value in app.white, the ScreenBitDepth is set to
                %                either 8-bit unsigned integer or 16-bit unsigned integer.
                switch app.white
                    case 255
                        app.ScreenBitDepth = 'uint8';
                    case 65535
                        app.ScreenBitDepth = 'uint16';
                    otherwise
                        error(['Data type was not reckognized please check the code ' ...
                            'to include this data. White value is' str2double(app.white)])
                end
                
                % Query duration of one monitor refresh interval:
                app.ifi=Screen('GetFlipInterval', app.w);
                
                % Translate that into the amount of seconds to wait between screen
                % redraws/updates:
                
                % waitframes = 1 means: Redraw every monitor refresh. If your GPU is
                % not fast enough to do this, you can increment this to only redraw
                % every n'th refresh. All animation paramters will adapt to still
                % provide the proper grating. However, if you have a fine grating
                % drifting at a high speed, the refresh rate must exceed that
                % "effective" grating speed to avoid aliasing artifacts in time, i.e.,
                % to make sure to satisfy the constraints of the sampling theorem
                % (See Wikipedia: "Nyquist?Shannon sampling theorem" for a starter, if
                % you don't know what this means):
                waitframes = 1;
                
                % Translate frames into seconds for screen update interval:
                waitduration = waitframes * app.ifi;
                
                % Perform initial Flip to sync us to the VBL and for getting an initial
                % VBL-Timestamp as timing baseline for our redraw loop:
                vbl=Screen('Flip', app.w);
                
            catch
                %this "catch" section executes in case of an error in the "try" section
                %above.  Importantly, it closes the onscreen window if its open.
                Screen('CloseAll');
                Priority(0);
                psychrethrow(psychlasterror);
            end %try..catch..
            
            % We're done!
            return
            
        end
        
        function CloseScreen(app)
            % function CloseScreen
            % ___________________________________________________________________
            %
            % Closes the OpenGL session and the buffered screen. Restores the prioriity level
            
            % HISTORY
            %  08/05/2011 gmr
            
            try
               	% This script calls Psychtoolbox commands available only in OpenGL-based
               	% versions of the Psychtoolbox. (So far, the OS X Psychtoolbox is the
               	% only OpenGL-base Psychtoolbox.)  The Psychtoolbox command AssertPsychOpenGL will issue
               	% an error message if someone tries to execute this script on a computer without
               	% an OpenGL Psychtoolbox
               	% AssertOpenGL;
                
                
                % following stuff to close the OpenGL session. To be transferred to
                % CloseScreen function
                % Restore normal priority scheduling in case something else was set
                % before:
                Priority(0);
                
               	%The same commands wich close onscreen and offscreen windows also close
               	%textures.
               	Screen('CloseAll');
                
            catch
                %this "catch" section executes in case of an error in the "try" section
                %above.  Importantly, it closes the onscreen window if its open.
                Screen('CloseAll');
                Priority(0);
                psychrethrow(psychlasterror);
            end %try..catch..
            
            % We're done!
            return
        end
        
        function EnableGoBtn(app,flag)
            switch flag
                case 1
                    app.FSFlashGo.Enable = 1;
                    app.CheckGo.Enable = 1;
                    app.GoGrid.Enable = 1;
                    app.ExpListGo.Enable = 1;
                    app.BusyStatusLamp.Color = [0 1 0];
                    if app.UdpConnectionFlag
                        app.StartUdpBtn.Enable = 1;
                    end
                case 0
                    app.FSFlashGo.Enable = 0;
                    app.CheckGo.Enable = 0;
                    app.GoGrid.Enable = 0;
                    app.ExpListGo.Enable = 0;
                    app.BusyStatusLamp.Color = [1 0 0];
                    if app.UdpConnectionFlag
                        app.StartUdpBtn.Enable = 0;
                    end
            end
        end
        
        function escherValue = Lumi2Escher(app,lumiValue)
            %         First of all, we need the gamma correction function of the screen
            screenFunc = str2func(app.ScreenFuncTxt.Value);
            %         Let's generate a vector of all possible values of escher,
            %         considering that psychtoolbox can only receive integer values
            %         that are in the range 0-app.white. Usually the range is 0-255 but
            %         let's be cautious
            allEscherX = (((1:app.white+1)-1)/app.white)';
            %             Now we generate a vector of all possible luminances that can
            %             be generated by Escher through psychtoolbox, by using the
            %             screen calibration function
            allLumiY = screenFunc(allEscherX);
            %             We look for the index of the value on the allLumiY vector
            %             that is close to "lumivalue" (our input).
            tmpValue = abs(allLumiY-lumiValue);
            [~,Idx] = min(tmpValue,[],1);
            %             Now we use that index to find the value on allEscherX: that
            %             is the value that we want as Escher input!
            escherValue = allEscherX(Idx);
        end
        
        function CheckUpdateLumi(app,updateContrastFlag)
            switch updateContrastFlag
                %                 If updateContrastFlag is 1, updates mean and contrast.
                %                 Otherwise it updates black and white lumi.
                case 1
                    %                     This function updates the mean luminance value and contrast
                    %                     value according to the black and white luminances that are
                    %                     set.
                    Blumi = app.CheckLumiB.Value;
                    Slumi = app.CheckLumiW.Value;
                    app.CheckLumiMean.Value = (Blumi+Slumi)/2;
                    app.CheckContrast.Value = (Slumi-Blumi)/(Blumi+Slumi)*100;
                case 0
                    %                     Update the black luminance and white luminance
                    %                     according to the mean luminance and contrast.
                    Mlumi = app.CheckLumiMean.Value;
                    contrast = app.CheckContrast.Value/100;
                    app.CheckLumiB.Value = Mlumi*(1-contrast);
                    app.CheckLumiW.Value = Mlumi*(1+contrast);
            end
            if app.CalibratedButton.Value
                app.CheckLumiBEsch.Value = Lumi2Escher(app,app.CheckLumiB.Value);
                app.CheckLumiWEsch.Value = Lumi2Escher(app,app.CheckLumiW.Value);
                app.CheckLumiMeanEsch.Value = Lumi2Escher(app,app.CheckLumiMean.Value);
            end
        end
        
        function dtrRect = DtrRectCalc(app)
            sR = app.screenRect;
            ww = app.wwTxt.Value; %width of the optical DTR
            hh = app.hhTxt.Value; %height of the optical DTR
            %             The optical DTR must be placed according to the selection
            %             of the position
            % position of DtrRect (x0, y0, x4, y4)
            
            dtrRect = [app.CueRightRB.Value*(sR(3)-ww),...
                app.CueBotRB.Value*(sR(4)-hh),...
                sR(3)-app.CueLeftRB.Value*(sR(3)-ww),...
                sR(4)-app.CueTopRB.Value*(sR(4)-hh)];
        end
        
        function TexStruct = CreateTextures(app, GratingStruct)
            TexStruct.Grating = Screen('MakeTexture', app.w, GratingStruct.Grating);   % about 2.5 ms
            TexStruct.GaussMask = Screen('MakeTexture', app.w, GratingStruct.GaussMask);
            TexStruct.DtrOnMask = Screen('MakeTexture', app.w, GratingStruct.DtrOnMask);
            TexStruct.DtrOffMask = Screen('MakeTexture', app.w, GratingStruct.DtrOffMask);
        end
    end
    
    methods (Access = private)
        
        
        function SelTab2Clip(app,mytable,selectedIndices)
            %         selectedIndices is a vector [a b c d], in which a and b are the
            %         row and column number of the top-left element of the selection. c
            %         and d are the row and column number of the bottom-right element.
            
            %         To copy the data from the table of zebrasays to the clipboard
            str = '';
            for i = selectedIndices(1):selectedIndices(3)
                for j = selectedIndices(2):selectedIndices(4)
                    if j == selectedIndices(4)
                        if isa(mytable.Data{i,j}, 'char')
                            str = sprintf('%s', str, mytable.Data{i,j});
                        else
                            str = sprintf('%s%f',str,mytable.Data{i,j});
                        end
                    else
                        if isa(mytable.Data{i,j}, 'char')
                            str = sprintf('%s\t', str, mytable.Data{i,j});
                        else
                            str = sprintf('%s%f\t',str, mytable.Data{i,j});
                        end
                    end
                end
                str = sprintf('%s\n',str);
            end
            clipboard('copy',str);
        end
        
        function refreshcamera(app)
            % Closing the device
            closedevice(app)
            
            app.PupilStruct.imaqhwinfoStruct = imaqhwinfo('winvideo'); % Open the structure imaqhwinfo with the adaptor 'winvideo'
            app.PupilStruct.camlist = cell(zeros(size(app.PupilStruct.imaqhwinfoStruct.DeviceInfo)));
            for i=1:length(app.PupilStruct.imaqhwinfoStruct.DeviceInfo) % Extract the camera list
                app.PupilStruct.camlist{i} = app.PupilStruct.imaqhwinfoStruct.DeviceInfo(i).DeviceName;
            end
            
            % Refreshing the popup menu
            app.CameraDropDown.Items = app.PupilStruct.camlist;
            
            % If possible, find the index of the USB camera
            usbcamIdx = find(contains(app.PupilStruct.camlist,'usb','IgnoreCase',true),1,'first'); % usb camera
            if ~isempty(usbcamIdx)
                app.PupilStruct.selcamIdx = usbcamIdx; % if there is a usb camera, select it
            elseif ~isempty(app.PupilStruct.camlist)
                app.PupilStruct.selcamIdx = 1; %if a usb camera is not there, select the first camera
            else
                app.PupilStruct.selcamIdx = [];
            end
            % app.selcamIdx will now contain the index of the selected camera
            
            selectcam(app)
        end
        
        function closedevice(app)
            %closepreview
            %             Check if the camera object is there
            if isfield(app.PupilStruct,'camobj')
                delete(app.PupilStruct.camobj)
            end
            
            app.CameraUnconnectedLamp.Color = [1 0 0];
            app.CameraUnconnectedLampLabel.Text = 'Camera Unconnected';
        end
        
        function selectcam(app)
            closedevice(app)
            
            if isempty(app.PupilStruct.selcamIdx)
                return
            end
            
            app.CameraDropDown.Value = app.PupilStruct.camlist{app.PupilStruct.selcamIdx};
            
            % Refreshing the formats
            camformats = app.PupilStruct.imaqhwinfoStruct.DeviceInfo(app.PupilStruct.selcamIdx).SupportedFormats;
            
            % Parsing of the formats
            camformats_nCols = zeros(size(camformats)); % double array containing the number of columns (pixel size of X Axis)
            camformats_nRows = zeros(size(camformats)); % y
            camformats_CA = cell(size(camformats));
            
            for i = 1:length(camformats)
                underscore_pos = find(camformats{i} == '_',1,'last');
                x_pos = find(camformats{i} == 'x',1,'last');
                camformats_nCols(i) = str2double(camformats{i}(x_pos+1:end));
                camformats_nRows(i) = str2double(camformats{i}(underscore_pos+1:x_pos-1));
                
                % camformats is a cell array that contains one column: the cell
                % array of strings describing all the resolution as "Xdimension x
                % Ydimenion"
                camformats_CA{i} = [num2str(camformats_nRows(i)),'x',num2str(camformats_nCols(i))];
            end
            
            % Let's refresh the list of formats
            app.ImageSizeDropDown.Items = camformats_CA;
            
            % Let's take the format corresponging to the smallest image size
            [~,app.PupilStruct.selformatIdx] = min(camformats_nCols .* camformats_nRows);
            app.PupilStruct.selformat = [camformats_nRows(app.PupilStruct.selformatIdx), camformats_nCols(app.PupilStruct.selformatIdx)];
            
            selectformat(app)
        end
        
        function selectformat(app)
            %% If the format changes, the device needs to be closed and connected again
            closedevice(app)
            
            % Recreate a format list
            camID = app.PupilStruct.selcamIdx;
            camformats = app.PupilStruct.imaqhwinfoStruct.DeviceInfo(camID).SupportedFormats;
            
            %% (Re-)Initializing the axes
            % The preview image is initialized
            app.PupilStruct.hPreviewImage = image(app.Image1UIAxes, uint8(zeros(app.PupilStruct.selformat(1),app.PupilStruct.selformat(2), 3)));
            app.Image1UIAxes.CameraUpVectorMode = 'manual';
            app.Image1UIAxes.CameraUpVector = [0 1 0];
            set(app.Image1UIAxes,'XTick',[], 'YTick', []) % Removes the ticks and labels from the axes
            
            % Draw the "rectangle" (actually an ellipse) on image 1
            app.PupilStruct.hRoi = rectangle(app.Image1UIAxes,'Position',[1,1,1,1],'EdgeColor',[1 0 0],...
                'Curvature', [1,1],'LineWidth',2);
            app.PupilStruct.hRoi.Visible = 'off';
            
            % % ------- THIS CAN BE UNCOMMENTED TO SPEED THE REFRESHING UP A LITTLE BIT
            % handles.image1_ax.Units = 'pixels';
            % % The axis dimensions are set to the same as the image size to speed
            % % everything up
            % handles.image1_ax.Position = [handles.image1_ax.Position(1),handles.image1_ax.Position(2),handles.selformat(1),handles.selformat(2)];
            % set(handles.image1_ax, 'xlimmode','manual','ylimmode','manual','zlimmode','manual','climmode','manual','alimmode','manual');
            % % -------
            
            % Axis 2 is for the binary image
            app.PupilStruct.hImage2 = image(app.Image2UIAxes, uint8(zeros(app.PupilStruct.selformat(1),app.PupilStruct.selformat(2))));
            app.Image2UIAxes.CameraUpVectorMode = 'manual';
            app.Image2UIAxes.CameraUpVector = [0 1 0]; % The Y axis must not be reversed
            set(app.Image2UIAxes,'XTick',[], 'YTick', []) % Removes the ticks and labels from the axes
            
            % % ------- THIS CAN BE UNCOMMENTED TO SPEED THE REFRESHING UP A LITTLE BIT
            % handles.image2_ax.Units = 'pixels';
            % % The axis dimensions are set to the same as the image size to speed
            % % everything up
            % handles.image2_ax.Position = [handles.image2_ax.Position(1),handles.image2_ax.Position(2),handles.selformat(1),handles.selformat(2)];
            % set(handles.image2_ax, 'xlimmode','manual','ylimmode','manual','zlimmode','manual','climmode','manual','alimmode','manual');
            % % -------
            
            %% Camera Object Creation
            % Select webcam and the correct format, and connect
            app.PupilStruct.camobj = videoinput('winvideo',camID,camformats{app.PupilStruct.selformatIdx});
            
            app.CameraUnconnectedLamp.Color = [0 1 0];
            app.CameraUnconnectedLampLabel.Text = 'Camera Connected!';
        end
        
        % This is for the segmentation of the pupil
        function [diametros,boundingBox,piel] = pupilSegment(app, rgbImage)
            la_imagen = rgb2gray(rgbImage);
            
            piel = ~imbinarize(la_imagen,app.PupilStruct.threshold);
            %     --
            piel = bwmorph(piel,'close');
            piel = bwmorph(piel,'open');
            piel = bwareaopen(piel,200);
            piel = imfill(piel,'holes');
            
            % Tagged objects in BW image
            L = bwlabel(piel);
            % Get areas and tracking rectangle
            out_a = regionprops(L);
            % Count the number of objects
            N = size(out_a,1);
            
            if N>=1 && ~isempty(out_a)
                % ---
                % Select larger area
                areas = [out_a.Area];
                [~, pam] = max(areas); % [area_max, pam] = max(areas);
                
                diametros = sqrt(out_a(pam).BoundingBox(3)*out_a(pam).BoundingBox(4));
                
                boundingBox = out_a(pam).BoundingBox;
            else
                diametros = NaN;
                boundingBox = NaN;
            end
        end
        
        % This is the timerFcn for the acquisition
        function AcquisitionTimerFcn(app)
            switch app.PupilStruct.acqTimerStatus
                case 0 % First time the callback is executing
                    app.PupilStruct.camobj.FramesPerTrigger = inf; % acquire until execution of method stop
                    %                     app.PupilStruct.camobj.FrameGrabInterval = app.frameGrabInterval; % acquire one frame every n frames
                    
                    % The start time of the acquisition, at the moment it's not used because the getdata function already gets the relative time of each frame from the beginning of the acquisition
                    %                    app.acqStartTime = clock;
                    
                    start(app.PupilStruct.camobj)
                    app.PupilStruct.acqTimerStatus = 1;
                    
                case 1 % Second time the callback is executing
                    stop(app.PupilStruct.camobj)
                    
                    if app.PreviewWhileAcquiringCheckBox.Value
                        PreviewStop(app)
                    end
                    
                    app.acqStatusTxt.Value = 'Retrieving data...';
                    
                    [app.PupilStruct.acqFrames, app.PupilStruct.acqtimeStamp] = getdata(app.PupilStruct.camobj);
                    
                    % analysis of the pupil size (can be skipped)
                    if app.skipPupilAcqCk.Value == 0
                        
                        diametros = zeros(size(app.PupilStruct.acqFrames,4),1);
                        
                        % Loop to extract the pupil segment from each acquired frame
                        for i=1:size(app.PupilStruct.acqFrames,4)
                            [diametros(i),~,~] = pupilSegment(app, app.PupilStruct.acqFrames(:,:,:,i));
                        end
                        
                        % At the end, the graph is updated with the plot of the pupil dimension
                        app.PupilStruct.hPupilSizePlot = plot(app.GraphUIAxes, app.PupilStruct.acqtimeStamp, diametros);
                    end
                    
                    app.acqStatus = 0;
                    
                    % Save file
                    app.acqStatusTxt.Value = 'Saving data...';
                    outputPath = [app.SaveFolderEditField.Value '\movie_' num2str(app.OutputFileN.Value,'%03.f') '.mat'];
                    while exist(outputPath,'file') == 2
                        app.OutputFileN.Value = app.OutputFileN.Value+1;
                        outputPath = [app.SaveFolderEditField.Value '\movie_' num2str(app.OutputFileN.Value,'%03.f') '.mat'];
                        
                    end
                    outputPath = [app.SaveFolderEditField.Value '\movie_' num2str(app.OutputFileN.Value,'%03.f') '.mat'];
                    outputArray = app.PupilStruct.acqFrames;
                    timeStamps = app.PupilStruct.acqtimeStamp;
                    save(outputPath, 'outputArray','timeStamps')
                    app.OutputFileN.Value = app.OutputFileN.Value+1;
                    app.PupilStruct.acqStatusTxt.Value = 'Data saved...';
                    % restore the acquisition button
                    app.acqLamp.Color = [0 1 0];
                    app.PupilStartAcquisitionButton.Enable = 1;
            end
        end
        
        function PreviewTimerFcn(app)
            app.PupilStruct.hPupilSizePlot.XData;
            
            % Let's take the approximate time
            timefromstart = etime(clock,app.PupilStruct.previewstarttime); % etime gives the time in seconds
            
            
            if app.skipPupilPrevCk.Value == 0
                app.PupilStruct.hRoi.Visible = 'on';
                [diametros,boundingBox,piel] = pupilSegment(app, app.PupilStruct.hPreviewImage.CData);
                
                app.PupilStruct.hImage2.CData = uint8(piel);
                
                if ~isnan(diametros)
                    %     colormap(handles.image1_ax,gray) % (eye,hsv)
                    app.PupilStruct.hRoi.Position = boundingBox;
                    app.PupilStruct.hPupilSizePlot.XData = [app.PupilStruct.hPupilSizePlot.XData(2:end),timefromstart];
                    xlim(app.GraphUIAxes, [-inf inf])
                    app.PupilStruct.hPupilSizePlot.YData = [app.PupilStruct.hPupilSizePlot.YData(2:end),diametros];
                    %ylim(handles.graph_ax, [0 inf])
                end
            else
                app.PupilStruct.hRoi.Visible = 'off';
            end
        end


        function PreviewInitialize(app)
            % Initialize the preview plot
            app.PupilStruct.hPupilSizePlot = plot(app.GraphUIAxes, -(0:(app.PupilStruct.previewgraph_size-1))', NaN(app.PupilStruct.previewgraph_size,1)); % Initialize
            app.GraphUIAxes.XLimMode = 'manual';
            app.GraphUIAxes.YLimMode = 'auto';
            xlabel(app.GraphUIAxes,'Time (s)')
            ylabel(app.GraphUIAxes,'Pupil Diameter (pixels)')
            
            preview(app.PupilStruct.camobj, app.PupilStruct.hPreviewImage) % Start the preview on the image "hPreviewImage"
            app.Image2UIAxes.Colormap = [0,0,0; 1,1,1]; % This is a binary image of the pupil. Don't know why, this works only in here (not in selecformat)
            app.PupilStruct.previewstarttime = clock;
            
            % Creating the preview timer
            % This is a timer object for the preview that executes at a high rate.
            % The busymode 'drop' makes sure that if the preview rate is too high, the
            % timer execution is just dropped and it doesn't clog anything
            app.PupilStruct.PreviewTimer = timer('BusyMode','drop','ExecutionMode','fixedRate','Period',0.5,'StartDelay',0.001,'TimerFcn',@(~,~) PreviewTimerFcn(app));
            
            start(app.PupilStruct.PreviewTimer)
        end
        
        function PreviewStop(app)
            closepreview % close the preview image
            stop(app.PupilStruct.PreviewTimer) % Stop the preview timer
            delete(app.PupilStruct.PreviewTimer) % Delete the preview timer
        end
        
        
        function CloseAllUdp(app)
            AllUdpObj = instrfindall('Type','udp');
            if ~isempty(AllUdpObj)
                stopasync(AllUdpObj)
                fclose(AllUdpObj);
                delete(AllUdpObj)
            end
        end
        
        function UdpDataReceivedCallback(app,obj)
            MsgIn = fscanf(obj);
%             Remove any white-space character.
            MsgIn = regexprep(MsgIn,'\s','');

            MsgCellArray = strsplit(MsgIn,',');
%             The first element of the cell array contains usually the MAC
%             address of the slave, but when the connection is started it
%             contains the word 'start'.
            if strcmp(MsgCellArray{1},'start')&&strcmp(MsgCellArray{2},app.SlaveIp.Value)
%                 The timeout timer must be stopped and deleted.
                stop(app.UdpTimeoutTimer)
                delete(app.UdpTimeoutTimer)
%                 The second and third word contain the IP and MAC
%                 respectively.
                app.SlaveMac.Value = MsgCellArray{3};
                
%                 The connection is set, so the buttons and other stuff must be changed
%                 accordingly.
                app.ConnectionStatus.Value = 'Connected.';
                app.ResendScreenParams.Enable = 1;
                app.UdpConnectionFlag = 1;
                app.UDPConnectionLamp.Color = [0 1 0];
%                 The button used for starting the connection now is used
%                 for canceling the connection.
                app.StartUdpBtn.Text = 'Stop Connection';
                EnableGoBtn(app,1)
                
            elseif strcmp(MsgCellArray{1},app.SlaveMac.Value)
%                 The timeout timer must be stopped and deleted.
                stop(app.UdpTimeoutTimer)
                delete(app.UdpTimeoutTimer)
                
                switch MsgCellArray{2}
                    %                     The second word in the message contains the
                    %                     instruction.
                    case 'screenok'
                      % Remember to set the fields relative to the screen size on the GUI and the
                      % relative app.variable.
                        app.WidthPix.Value = str2double(MsgCellArray{3});
                        app.HeightPix.Value = str2double(MsgCellArray{4});
                        
                        app.OpenScreenBtn.Text = 'Close Screen';
                        app.ScreenOpenFlag = 1;
                    case 'wait'
%                         A timer for timeout has to be called. Before that, the
%                         current timer is stopped and deleted.
                        if isvalid(app.UdpTimeoutTimer)
                            stop(app.UdpTimeoutTimer)
                        end
                        delete(app.UdpTimeoutTimer) % maybe it's an overkill?
                        app.UdpTimeoutTimer = timer('ExecutionMode','singleShot','StartDelay',...
                            str2double(MsgCellArray{3})+app.TimeoutTime.Value,'TimerFcn',@(obj,~) UdpTimeoutCallback(app,obj));
                    case 'stimok'
%                         Receive timestamps of all stimuli and prints them
%                         into current log file.
                        app.CurrentLogStruct.T.Timestamps(1)=MsgCellArray{3};
                        writetable(app.CurrentLogStruct.T,app.CurrentLogStruct.logName)
%                         The stimulus has been performed, therefore the
%                         master will stop the timer and another stimulation can start.
                        EnableGoBtn(app,1)
                    case 'busy'
%                         If the slave is busy, the timer for timeout must
%                         be stopped and the master can continue listening.
                        disp('Slave is busy.')
                    case 'ok'
%                         If the slave is busy, the timer for timeout must
%                         be stopped and the master can continue listening.
                        disp('Slave is ok.')
                    otherwise
                        warning(['Message not recognized: ', MsgIn])
                end
            end
        end
        
        function UdpTimeoutCallback(app,obj)
%             This is called only when a message is sent through UDP but no
%             answer is received.
            uialert(app.EscherMasterFig,'UDP connection lost.','Error')
%             The connection status is set to 1, so that the button to
%             cancel the connection is called.
            app.UdpConnectionFlag = 1;
            StartUdpBtnButtonPushed(app,[])
%             Similarly, the ScreenOpenFlag is set to 1 and "Close Screen"
%             button is pushed.
            app.ScreenOpenFlag = 1;
            OpenScreenBtn_Callback(app, [])
            
%             Then, the timer is stopped and deleted.
            stop(obj)
            delete(obj)
        end
        
        function SendUdpMsg(app,msg,TimeoutTime)
%             A timer for timeout has to be called. Before that, the
%             current timer is stopped and deleted.
            if isvalid(app.UdpTimeoutTimer)
                stop(app.UdpTimeoutTimer)
            end
            delete(app.UdpTimeoutTimer) % maybe it's an overkill?
            app.UdpTimeoutTimer = timer('ExecutionMode','singleShot','StartDelay',...
                TimeoutTime,'TimerFcn',@(obj,~) UdpTimeoutCallback(app,obj));
%             The timer is started before sending the UDP message. This is
%             to avoid that a UDP answer arrives (and stops the timer)
%             before the timer is started, since the two objects are
%             asynchronous.
            start(app.UdpTimeoutTimer)
            readasync(app.UdpObj)
            app.UdpObj.ReadAsyncMode = 'continuous';
            fprintf(app.UdpObj,msg);
        end
        
        function OnePxAngle = PixelAngleCalc(app)
            % Compute angular size of single pixel
            Wpx = app.WidthPix.Value; % Number of pixels in the screen width
            ScWidth = app.ScreenWidth.Value; % Screen width in centimeters
            ScDist = app.ScreenDistance.Value; % Distance of the screen in centimeters
%             The angle of a single pixel is returned.
            OnePxAngle = atand((ScWidth/Wpx)/ScDist);    % atand returns the angle in degrees
        end
        
        function msg = ScreenParameterMsg(app)
            msg=strjoin([{app.MasterMac.Value},{'screen'},{'1'},...
                arrayfun(@num2str,[app.ScreenDistance.Value,app.ScreenWidth.Value,...
                app.ScreenHeight.Value,app.CalibratedButton.Value],'un',0),...
                {app.ScreenFuncTxt.Value},arrayfun(@num2str,[app.ard_ck.Value,app.wwTxt.Value,...
                app.hhTxt.Value,app.optDtrTimeTxt.Value,app.CueTopRB.Value,...
                app.CueLeftRB.Value,app.OneScreenMode_ck.Value,app.oculusCk.Value],'un',0)],',');
        end
    end
    
    methods (Access = private)        
        function GratingStruct = CreateGratings(app, Glumi, inc, f, gridType, MaskFlag, GaussSize)
%             this function creates the grid texture (either square of sinusoidal grid)
%             of frequency f and the Gaus mask of size GaussSize
%             The original outputs were [gratingsize, visiblesize, gratingtex,
%             masktex].
%             Right now:
%             Glumi is the average luminance of the stimulus: (Slumi+Blumi)/2
%             Contrast 'inc'rement range for given white and gray values: (Slumi-Blumi)/2
%             f is the spatial frequency (in terms of cycles/pixel)
%             gridType is 1 is 1 if the grid is sinusoidal. 0 for square-wave grid.
%             MaskFlag 1 means that gaussian mask is applied to the stimulus.
%             GaussSize is the size of gaussian mask in pixels.
            
            sR = app.screenRect;
            
            % Calculate parameters of the grating:
            
            % First we compute pixels per cycle, rounded up to full pixels, as we
            % need this to create a grating of proper size below:
            p = ceil(1/f);
            
            % Also need frequency in radians:
            fr = f*2*pi;
            
            %             ENRICO 20190504 I removed this
            %             % This is the visible size of the grating. It is twice the half-width
            %             % of the texture plus one pixel to make sure it has an odd number of
            %             % pixels and is therefore symmetric around the center of the texture:
            %             visiblesize = 2*texsize+1;
            
            % Create one single static grating image:
            %
            % We only need a texture with a single row of pixels(i.e. 1 pixel in height) to
            % define the whole grating! If the 'srcRect' in the 'Drawtexture' call
            % below is "higher" than that (i.e. visibleSize >> 1), the GPU will
            % automatically replicate pixel rows. This 1 pixel height saves memory
            % and memory bandwith, ie. it is potentially faster on some GPUs.
            %
            % However it does need 2 * texsize + p columns, i.e. the visible size
            % of the grating extended by the length of 1 period (repetition) of the
            % sine-wave in pixels 'p':
            
            %             ENRICO 20190504 Why meshgrid here? A vector is enough
            %             x = meshgrid(-texsize:texsize + p, 1);
            %             The dimension is the length of the diagonal of the screen
            %             (calculated by Pythagorean theorem. Plus one period, to cover
            %             all the screen during the drifting.
            x = 0:ceil(sqrt(sR(3)^2+sR(4)^2))+p;
            
            %             Compute actual cosine grating:
            if gridType == 1
                grating = Glumi + inc*cos(fr*x);
            else
                %                 Square-wave stimulus
                GridUp = Glumi + inc;
                GridDown = Glumi - inc;
                grating = zeros(size(x));
                for ii = 1:length(x)
                    if cos(fr*x(ii))>0
                        grating(ii) = GridUp;
                    else
                        grating(ii) = GridDown;
                    end
                end
            end
            
            if app.CalibratedButton.Value
                grating = arrayfun(@(x) Lumi2Escher(app,x), grating, 'un',1);
                
                %                 You cannot convert the Glumi value before this point
                %                 because you need the uncalibrated value for the
                %                 generation of the stimulus.
                Glumi = Lumi2Escher(app, Glumi);
            end
            Glumi = app.white * Glumi;
            %             Convert grating to values from 0 to app.white (usually 255)
            grating = grating*app.white;
            
%             Here it could be possible not to execute this block and let the graphic card repeat the grating.
%             Instead, we create a full 2-D grating.
                %                 Horizontally stacks the row vector "grating" a number of
                %                 times equal to its length. "grating" becomes a square
                %                 matrix.
            grating = repmat(grating, length(grating), 1);
            
            % Create a single gaussian transparency mask and store it to a texture:
            % The mask must have the same size as the visible size of the grating
            % to fully cover it. Here we must define it in 2 dimensions and can't
            % get easily away with one single row of pixels.
            %
            % We create a  two-layer texture: One unused luminance channel which we
            % just fill with the same color as the background color of the screen
            % 'gray'. The transparency (aka alpha) channel is filled with a
            % gaussian (exp()) aperture mask.
            
            % from here to ...
            
            %             ENRICO 20190504 Let's try to change the gauss mask size.
            %             gaussmask = ones(2*texsize+1, 2*texsize+1, 2) * Glumi;
            %             [x,y] = meshgrid(-1*texsize:1*texsize,-1*texsize:1*texsize);
            gaussmask = Glumi * ones(sR(4), sR(3), 2);
            [x,y] = meshgrid(-(sR(3)-1)/2 : (sR(3)-1)/2,...
                -(sR(4)-1)/2 : (sR(4)-1)/2);
            %             Now the gaussian mask has the same dimension as the screen.
            
            if MaskFlag
                %                 ENRICO 20190504 Here, a 2-D Gaussian function is used, to
                %                 fill the alpha channel of gaussmask. The function is 0 at
                %                 the center of the screen (transparent) and goes up to 255
                %                 as it goes further away from the center (opaque).
                %                 The sigma (standard deviation) of the Gaussian function
                %                 is sqrt(2)*GaussSize. This means that at the distance
                %                 GaussSize, the function is 63% of the maximum (160/255).
                gaussmask(:, :, 2) = 255 * (1 - exp(-((x/GaussSize).^2)-((y/GaussSize).^2)));
                
            else
                gaussmask(:, :, 2) = 0;
            end
            %             Now:
            %             gaussmask(:,:,1) is gray everywhere.
            %             gaussmask(:,:,2) has value of 255 where the mask is opaque. While
            %             it's 0 where the mask is transparent.
            
            
            %             OPTICAL DTR PREPARATION
            %             The mask for the optical DTR is prepared anyway, but it stays
            %             transparent (alpha=0) if the optical DTR is not checked on
            %             the GUI.
            mask_on = zeros(sR(4), sR(3), 2);
            mask_on(:, :, 1) = app.white; % white everywhere
            mask_off = zeros(sR(4), sR(3), 2); %black everywhere
            %             Only if the optical DTR is checked, the alpha channel is made
            %             opaque only where the optical DTR should appear.
            if app.ard_ck.Value
                dtrRect = DtrRectCalc(app);
                mask_on(dtrRect(2)+1:dtrRect(4), dtrRect(1)+1:dtrRect(3), 2) = 255; %255 is opaque
                mask_off(dtrRect(2)+1:dtrRect(4), dtrRect(1)+1:dtrRect(3), 2) = 255; %255 is opaque
            end
            
            %             Cast grating, gaussmask and mask_on mask_off to the correct
            %             screen bit depth (usually 8 bit).
            grating = cast(grating, app.ScreenBitDepth);
            gaussmask = cast(gaussmask, app.ScreenBitDepth);
            mask_on = cast(mask_on, app.ScreenBitDepth);
            mask_off = cast(mask_off, app.ScreenBitDepth);
            
            %             Now all the gratings (including the masks) are passed to a
            %             structure called GratingStruct, which is the function output.
            GratingStruct.Grating = grating;
            GratingStruct.GaussMask = gaussmask;
            GratingStruct.DtrOnMask = mask_on;
            GratingStruct.DtrOffMask = mask_off;
        end
                
        function SetScreenSizeAngle(app)
            % called after any changes of the screen geometry or position.
            WAngle = round(atand(app.ScreenWidth.Value/app.ScreenDistance.Value));
            HAngle = round(atand(app.ScreenHeight.Value/app.ScreenDistance.Value));
            Sz = [int2str(WAngle) '° x ' int2str(HAngle) '°'];
            app.ScreenSizeAngle.Value = Sz;
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            AppFilePath = mfilename('fullpath');
%             According to the operating system, sets a different separator
%             used for folder paths.
            if ispc
                app.PathSeparator = '\';
            elseif isunix
                app.PathSeparator = '/';
            else
                app.PathSeparator = '';
                error('Platform not recognized. Please check the startup function of the app.\n')
            end
%             Take all the characters up to the last path separator. This
%             is the Escher App Folder.
            AppFolder = regexp(AppFilePath, ['^.*\',app.PathSeparator],'match');
            app.escherAppFolder = AppFolder{:};
            
%             INITIALIZE ALL FLAGS
            %             The screen is not open in the beginning, so the corresponding
            %             flag is set to zero
            app.ScreenOpenFlag = 0;
            app.UdpConnectionFlag = 0;
            
%             Erase all UDP connections and initialize a UDP object.
            CloseAllUdp(app)
            app.UdpObj = udp;
            
%            Initialisation code for EditText controls
%            Here the content of each editable field of the GUI is loaded in a newly
%            created variable in the 'app' structure. I am doing that since I
%            find easier to use a app.variableName sintax to refer to a number
%            rather than 'get'ting it from the control 'string' property. Of course
%            code must be written inn the callback functions to keep the
%            'app.variable' sinched to the 'string' fields.
            
%            Sinusoidal gratings
%             app.SliderOrientation.Value = app.GridOrientationV;
            
%             Monitor initialisation and geometry
            
            SetScreenSizeAngle(app)
                        
%             Preparation of the log output
%
%             Each time Esher is launched check the log directory *\logs for the
%             presence of a directory named with the current date. If it does not
%             exist it is created.
%             
%             Content of the log directory.
%             Each time a stim event is created (either a single episode or an entire
%             sequence) a new entry is created in the log directory.
%             Sintax of the file name:
%             It is formed by two fields, separated by dot.
%             The first field is the run number (4 digits).
%             The second field define the type of stimulus
%             flash      full field flash
%             check      checkerboard
%             grid       presentation of a grid at a given orientaton
            
%             Finally the extension .dat
            
%             Each run file includes a line that contains all the parameters relevant
%             to describe the specific stim protocol. Each presentation is described
%             in subsequent lines. The first field is a time tag, followed by
%             parameters desribing the specific stimulus, i.e. the grid orientation.
            
            app.logDir = [app.escherAppFolder, 'logs', app.PathSeparator, date, app.PathSeparator];
            app.runNumberFile = [app.logDir, 'run.number.dat'];
            if exist(app.logDir, 'dir') == 7
                
%             The log directory has already been created. Determine the next run number.
%             This is contained in the ascii file run.number.log that is created at
%             the time of the directory creation. Each time a new log item is added
%             the run.number is updated. If Escher2 is re-initialisated during a
%             single session, this ensure the unicity of the log file names.
                app.runNumber = readmatrix(app.runNumberFile);
            else
                % Create the ASCII run.number.log file. run number = 1, of course
                mkdir(app.logDir)
                writematrix(1, app.runNumberFile)
            end
            
%             app.white is initialized to 255 and the ScreenBitDepth to 'uint8'.
%             This is the default. If the screen is opened with a different
%             bit depth, these values will be updated.
            app.white = 255;
            app.ScreenBitDepth = 'uint8';
            
%             HARD-CODED SETTINGS AND CALLBACKS
%             The table for fast selection of Flash stimuli has to be set
            app.FlashFastTable.Data = [1, 0, 0; 2, 0.1, 0; 3, 0.139, 0;...
                4, 0.194, 0; 5, 0.268, 0; 6, 0.374, 0; 7, 0.516, 0;...
                8, 0.719, 0; 9, 1, 0];
            app.FlashFastTotN.Value = size(app.FlashFastTable.Data, 1);
%             Then executes the callback of the fast set text box.
            FlashFastN_Callback(app,[])
            
%             The table for fast selection of Checkerboard stimuli has to
%             be set
            app.CheckFastTable.Data = [1, 0, 0.6; 2, 6, 0.6; 3, 12, 0.6;...
                4, 25, 0.6; 5, 50, 0.6; 6, 95, 0.6];
            app.CheckFastTotN.Value = size(app.CheckFastTable.Data, 1);
%             Then executes the callback of the fast set text box.
            CheckFastNValueChanged(app,[])
            
            app.OrientFastTable.Data = [1, 0; 2, 90; 3, 180; 4, 270];
            app.OrientFastTotN.Value = size(app.OrientFastTable.Data, 1);
            OrientFastNValueChanged(app, [])
            
%             The timer for UDP connection timeout must be initialized.
            app.UdpTimeoutTimer = timer;
            
%             Other callbacks to be exectued:
%             The callback for the calculation of Escher values from
%             luminances (in case the calibration is on) OR hiding the
%             escher values (in case calibration is off).
%             All this is achieved by invoking the callback of the
%             calibration radio button.
            CalModeRBGroupSelectionChanged(app,[])
            
%             This is the initialization of the colors of the ExpList.
%             The first is the background color, then the current exp
%             color, then the running exp and the completed exp.
            app.ExpListColors = [1 1 1; 0.9 0.9 0.9; 1 1 0.8; 0.85 1 0.85];
            
%             Creates a vector of graphical objects, containing all the
%             labels of luminance, which have to change between cd/m2 and
%             0-1, depending on whether the absolute luminance or the
%             "Escher Value" (value between 0 and 1) is selected.
            app.LabeLumiVector = [app.LabelLumi_1, app.LabelLumi_2,...
                app.LabelLumi_3, app.LabelLumi_4,app.LabelLumi_5,...
                app.LabelLumi_6];
            
%             Initialize the ExperimentTable so that the stimulus type can
%             be selected with a dropdown menu.
            app.ExperimentTable.ColumnFormat = {'numeric',{'flash','checkerboard','grid'},'numeric','char'};
            
%             Initialize the IP and MAC for UDP connection.
            GetMasterIPandMACBtnPushed(app,[])
            
%             Pupillometry initialization
            app.PupilStruct.preview_status = 0; % This is 1 while the preview is on
            app.PupilStruct.previewRate = app.RatefpsEditField.Value; % This is the framerate of the preview
            app.PupilStruct.acqStatus = 0; % This is set to 1 when the acquisition has started
            app.PupilStruct.threshold = app.ThresholdSlider.Value;
% set(gcf,'doublebuffer','off') % Set the DoubleBuffer property on for to obtain flicker-free animation. However, to maximize rendering speed, set DoubleBuffer to off.
            app.PupilStruct.previewgraph_size = app.NumberofPointsinPreviewGraphEditField.Value; % number of points composing the preview graph
            app.PupilStruct.acqDuration = app.DurationsEditField.Value;
            
            refreshcamera(app)
        end

        % Value changed function: DriftingSpeed_txt
        function DriftingSpeed_txt_Callback(app, event)
            app.DriftingSpeed = app.DriftingSpeed_txt.Value;
        end

        % Button pushed function: FSFlashGo
        function GoFlash_Callback(app, event)
            % December , 2018
            % The app has been completey redesigned to implement a server-client architecture
            
            % Nov 25, 2013
            % the function has been modified to allow the display of a periodically
            % alternating checkboard.
            % Blumi and Slumi define the luminance of the black and light squares.
            % The spatial frequency is defined by the "spatial frequency" field in the
            % grid generation module.
            % p define the period of the alternation (ON and OFF)
            % Bt defines the baseline_txt spent in the baseline_txt equiluminant gray computed
            % as (Blumi+Slumi)/2 if a calibration LUT is active.
            
            % May 2017.
            % This code has been modified to allow the creation of the checkerboad stim
            % on the VR googles.
            
            %             Disables all "Go" buttons
            EnableGoBtn(app,0)
            
            % load parameters in local variables
            Blumi = app.FSbaselineL.Value; % baseline luminance
            Slumi = app.FSflashL.Value; % flash luminance
            Bt = app.initialDelay.Value; % an initial delay (just once)
            St = app.FlashDuration.Value; % The duration of a single flash
            p = app.FlashPeriod.Value; % time between each flash
            n = app.FlashRepetitions.Value;   % number of repetitions
            
            % Writing the log file.
            app.runNumber = readmatrix(app.runNumberFile);
            runString = sprintf('%04d', app.runNumber);
            app.CurrentLogStruct.logName = [app.logDir, runString, '.flash', '.dat'];
%             Initial writing of the file. The file contains the whole
%             table, but the last column (the timestamps) is empty.
            app.CurrentLogStruct.T = table('Size',[1 8],'VariableTypes',[{'datetime'},...
                repmat({'double'},[1, 6]),{'string'}],'VariableNames',...
                {'StartTime','Blumi','Slumi','Bt','St','p','n','Timestamps'});
            app.CurrentLogStruct.T.StartTime(1) = datetime;
            app.CurrentLogStruct.T{1,2:end-1} = [Blumi,Slumi,Bt,St,p,n];
            % Write the table to the log file for this stimulation.
            writetable(app.CurrentLogStruct.T,app.CurrentLogStruct.logName)
%             Updates the runNumber file.
            app.runNumber = app.runNumber +1;
            writematrix(app.runNumber, app.runNumberFile)
            
            if app.UdpConnectionFlag
                msg=strjoin([{app.MasterMac.Value},{'flash'},arrayfun(@num2str,[Blumi,Slumi,Bt,St,p,n],'un',0)],',');
                SendUdpMsg(app,msg,app.TimeoutTime.Value)
                return
            end
            
%             Starts the OpenGL session if in single screen mode
            if app.OneScreenMode_ck.Value
                OpenScreen(app, Blumi)
            end
            
            ard_flag = app.ard_ck.Value;
            
            %             If the calibration is selected, this converts the values of
            %             luminance (cd/m2) into "Escher values" (between 0 and 1).
            if app.CalibratedButton.Value
                Blumi = Lumi2Escher(app,Blumi);
                Slumi = Lumi2Escher(app,Slumi);
            end
            
            Blumi = app.white*Blumi;             % Luminance expressed as fractions of 'white'
            Slumi = app.white*Slumi;
                        
            %             New part. Enrico 2019/05/24
            if ard_flag
                dtrRect = DtrRectCalc(app);
                BaselineColor = cast([[Blumi;Blumi;Blumi], [0;0;0]], app.ScreenBitDepth);
                StimColor = cast([[Slumi;Slumi;Slumi], [app.white;app.white;app.white]], app.ScreenBitDepth);
                cellRects = [app.screenRect; dtrRect]';
                %                 This is commented because in flashes, the optical DTR
                %                 duration is the same as the flash duration
                %                 optDtrTime = app.optDtrTimeTxt.Value/1000; % optical DTR duration in s
            else
                BaselineColor = cast(Blumi, app.ScreenBitDepth);
                StimColor = cast(Slumi, app.ScreenBitDepth);
                cellRects = app.screenRect;
            end
            
%             This vector contains two timestamps for each flash. One is
%             for the flash turning on and one for the flash turning off.
            timestamps = NaN(1,2*n);
            % Load the WaitSecs function. The first load might take some
            % time.
            WaitSecs(0);
            
%             ACTUAL START OF STIMULATION
            % December 2018. The initial delay is outside of the stimulus loop
            % Paint the screen for the initial delay
            % the following two lines takes a variable time in the range of 7-19 ms.
            try
                Screen('FillRect', app.w, BaselineColor, cellRects); % paints the rectangle (entire screen)
                Screen('Flip', app.w);                            % bring the buffered screen to forefront
%             Let's acquire the starting time.
                timZero = WaitSecs(0);
                
                timOffset = timZero + Bt;
                
                for i = 1 : n
                    % Wait for the end of the period. For the first period,
                    % it just waits for the end of the baseline.
                    timStart = timOffset + (i-1) * p;
                    timEnd = timStart+St;
                    
                    % next lines flashes the screen with or without the Hermes rectangle. 6-12 ms on the Acer
                    %for flashes we suppose that stim duration is 20-100ms, which is compatible with Hermes detection and idle time
                    Screen('FillRect', app.w, StimColor, cellRects); % paint the rectangle (entire screen)
                    timestamps(2*i-1) = Screen('Flip', app.w, timStart);
                    
                    % the flash is complete
                    Screen('FillRect', app.w, BaselineColor, cellRects); % paint the rectangle (entire screen)
                    timestamps(2*i) = Screen('Flip', app.w, timEnd); % wait for the end of stim
                end
            catch ME
                warning(ME.message)
            end
            if app.OneScreenMode_ck.Value
                CloseScreen(app)
            end
            
            timestamps = timestamps - timZero;
%             Timestamps are stored in the log file as a character vector,
%             in which timestamps are separated by a semi-colon.
%             Timestamps are saved with 6 significant digits after decimal point (microseconds).
            app.CurrentLogStruct.T.Timestamps(1) = strjoin(arrayfun(@(x) num2str(x,'%.6f'),timestamps,'un' ,0),';');
            % Write the table to the log file for this stimulation.
            writetable(app.CurrentLogStruct.T,app.CurrentLogStruct.logName)
            
            %             Enable the buttons "Go" again
            EnableGoBtn(app,1)
        end

        % Button pushed function: GoGrid
        function GoGrid_Callback(app, event)
            %             Disables all "Go" buttons
            EnableGoBtn(app,0)
            
            % The two luminance values we need
            Glumi = app.CheckLumiMean.Value; % the average luminance
            inc = (app.CheckLumiW.Value-app.CheckLumiB.Value)/2; % the increment of luminance
%             Other values needed
            sF = app.spatialFrequency.Value; % spatial frequency of the stimulus (in cycles/degree)
            gridType = app.SineGrid.Value; % 1 means sinusoidal, 0 is for square-wave (bar)
            Angle = app.GridOrientation.Value; % angle of the stimulus. 0 means vertical bars.
            dS = app.DriftingSpeed_txt.Value; % drifting speed of the stimulus in cycles/s.
            MaskFlag = app.DrawMask.Value; % 1 means that a gaussian mask is applied.
            GaussSize = app.GaussSize_txt.Value; % size of gaussian mask
            Bt = app.initialDelay.Value; % initial delay
            PreG = app.PreGridDuration.Value; % pre-grid duration
            GridT = app.GridDuration.Value; % grid duration
            PostG = app.PostGridDuration.Value; % post-grid duration
            n = app.GridRepetitions.Value; % number of repetitions of the stimulus
            
            % Writing the log file.
            app.runNumber = readmatrix(app.runNumberFile);
            runString = sprintf('%04d', app.runNumber);
            app.CurrentLogStruct.logName = [app.logDir, runString, '.grid', '.dat'];
%             Initial writing of the file. The file contains the whole
%             table, but the last column (the timestamps) is empty.
            app.CurrentLogStruct.T = table('Size',[1, 17],'VariableTypes',[{'datetime'},...
                repmat({'double'},[1, 15]),{'string'}],'VariableNames',...
                {'StartTime','Blumi','Slumi','MeanLumi','K','sF', 'sine','angle','speed',...
                'maskflag','masksize','Bt','preG','gridT','postG','n','Timestamps'});
            app.CurrentLogStruct.T.StartTime(1) = datetime;
            app.CurrentLogStruct.T{1,2:end-1} = [app.CheckLumiB.Value,app.CheckLumiW.Value,...
                Glumi,app.CheckContrast.Value,sF,gridType,Angle,dS,MaskFlag,GaussSize,...
                Bt,PreG,GridT,PostG,n];
            % Write the table to the log file for this stimulation.
            writetable(app.CurrentLogStruct.T,app.CurrentLogStruct.logName)
%             Updates the runNumber file.
            app.runNumber = app.runNumber +1;
            writematrix(app.runNumber, app.runNumberFile)
            
            if app.UdpConnectionFlag
                msg=strjoin([{app.MasterMac.Value},{'grid'},arrayfun(@num2str,...
                    [Glumi,inc,sF,gridType,Angle,dS,MaskFlag,GaussSize,Bt,PreG,GridT,PostG,n],'un',0)],',');
                SendUdpMsg(app,msg,app.TimeoutTime.Value)
                return
            end
            
            if app.OneScreenMode_ck.Value
                OpenScreen(app,app.CheckLumiMean.Value)
            end
            
            ard_flag = app.ard_ck.Value;
            sR = app.screenRect;
            
            % Compute angular size of single pixel
            OnePxAngle = PixelAngleCalc(app);    % atand returns the angle in degree
            f = OnePxAngle*sF;
            
            %             The stimulus has to be prepared BEFORE the conversion to the
            %             calibrated look up table
            %             ENRICO 2018/05/02 Let's prepare the gratings and masks in
            %             advance
            GratingStruct = CreateGratings(app, Glumi, inc, f, gridType, MaskFlag, GaussSize);
            
            %             This calibration shouldn't be performed before this point, because
            %             the luminance is needed as a non-corrected value for the
            %             CreateGratings function.
            if app.CalibratedButton.Value
                Glumi = Lumi2Escher(app,Glumi);
            end
            Glumi = Glumi*app.white;
            
%             Outside the DTR conditional block, because some DTR
%             parameters are used anyway.
            dtrRect = DtrRectCalc(app);
            optDtrTime = app.optDtrTimeTxt.Value/1000; % optical DTR duration in s
            if ard_flag % prepare the additional rectangle for optical synch signal
                BaselineColor = cast([[Glumi;Glumi;Glumi], [0;0;0]], app.ScreenBitDepth);
                BaselineScreen = [app.screenRect; dtrRect]';
            else
                BaselineColor = cast(Glumi, app.ScreenBitDepth);
                BaselineScreen = app.screenRect;
            end
                        
%             Before starting we need to compute some parameters needed for
%             the animation of the grating.
%             First of all, the total duration of one period is computed.
            totPeriod = PreG+GridT+PostG;
%             Definition of the drawn rectangle on the screen:
%             Compute it to  be the visible size of the grating, centered on the
%             screen:
%             ENRICO 20190504 Removed the destination rect of variable
%             size. Now the destination rect is the whole screen.
%             dstRect = [0 0 visiblesize visiblesize];
%             dstRect = CenterRect(dstRect, app.screenRect);
%             Translate that into the amount of seconds to wait between screen
%             redraws/updates:
%             waitframes = 1 means: Redraw every monitor refresh. If your GPU is
%             not fast enough to do this, you can increment this to only redraw
%             every n'th refresh. All animation paramters will adapt to still
%             provide the proper grating. However, if you have a fine grating
%             drifting at a high speed, the refresh rate must exceed that
%             "effective" grating speed to avoid aliasing artifacts in time, i.e.,
%             to make sure to satisfy the constraints of the sampling theorem
%             (See Wikipedia: "Nyquist?Shannon sampling theorem" for a starter, if
%             you don't know what this means):
            waitframes = 1;
%             Translate frames into seconds for screen update interval:
            waitduration = waitframes * app.ifi;
%             Recompute p, this time without the ceil() operation from above.
%             Otherwise we will get wrong drift speed due to rounding errors!
            p = 1/f;  % pixels/cycle
%             Translate requested speed of the grating (in cycles per second) into
%             a shift value in "pixels per frame", for given waitduration: This is
%             the amount of pixels to shift our srcRect "aperture" in horizontal
%             directionat each redraw:
            shiftperframe = dS * p * waitduration;
%             Prepare the grating textures in advance.
            TexStruct = CreateTextures(app, GratingStruct);
            
%             This vector contains two timestamps for each period, one for
%             the beginning of the grid and one for the end.
            timestamps = NaN(1,2*n);
%             Load the WaitSecs function.
            WaitSecs(0);
            
%             START OF THE ACTUAL STIMULATION
%             ENRICO. 26/08/2019 Added an initial delay
            try
                Screen('FillRect', app.w, BaselineColor, BaselineScreen);     % paints the rectangle (entire screen)
                Screen('Flip', app.w);  % bring the buffered screen to forefront
                timZero = WaitSecs(0);
                timOffset = timZero + Bt;
                
                i = 1;
                while i<=n
%                     First off, fill the screen with uniform gray background
%                     for the pre-stimulus baseline.
                    Screen('FillRect', app.w, BaselineColor, BaselineScreen);
                    timStart = timOffset+(i-1)*totPeriod+PreG; % start of the grid
                    dtrendtime = timOffset+(i-1)*totPeriod+PreG+optDtrTime;
                    timEnd = timOffset+(i-1)*totPeriod+PreG+GridT; % end of the grid
                    
%                     This flip is at the beginning of the Grid
%                     presentation, right after the pre-grid period.
                    vbl = Screen('Flip', app.w, timStart);  % bring the buffered screen to forefront
                    timestamps(2*i-1) = vbl;
                    
                    ii=0;
        %             Animationloop:
                    while vbl < timEnd
                        
        %                 Shift the grating by "shiftperframe" pixels per frame:
        %                 the modulo operation makes sure that our "aperture" will snap
        %                 back to the beginning of the grating, once the border is reached.
        %                 Fractional values of 'xoffset' are fine here. The GPU will
        %                 perform proper interpolation of color values in the grating
        %                 texture image to draw a grating that corresponds as closely as
        %                 technical possible to that fractional 'xoffset'. GPU's use
        %                 bilinear interpolation whose accuracy depends on the GPU at hand.
        %                 Consumer ATI hardware usually resolves 1/64 of a pixel, whereas
        %                 consumer NVidia hardware usually resolves 1/256 of a pixel. You
        %                 can run the script "DriftTexturePrecisionTest" to test your
        %                 hardware...
                        xoffset = mod(ii*shiftperframe, p);
                        
        %                 Define shifted srcRect that cuts out the properly shifted rectangular
        %                 area from the texture: We cut out the range 0 to visiblesize in
        %                 the vertical direction although the texture is only 1 pixel in
        %                 height! This works because the hardware will automatically
        %                 replicate pixels in one dimension if we exceed the real borders
        %                 of the stored texture. This allows us to save storage space here,
        %                 as our 2-D grating is essentially only defined in 1-D:
                        srcRect = [xoffset, 0, ceil(sqrt(sR(3)^2+sR(4)^2))+xoffset, ceil(sqrt(sR(3)^2+sR(4)^2))];
                        dstRect = [0, 0, ceil(sqrt(sR(3)^2+sR(4)^2)), ceil(sqrt(sR(3)^2+sR(4)^2))];
                        dstRect = CenterRect(dstRect, app.screenRect);
                        
                        if vbl<dtrendtime
        %                     This is the beginning of the stimulus, when the
        %                     optical DTR has to be white.
                            
        %                     Draw grating texture, rotated by "angle".
        %                     Then it draws the gaussian mask.
        %                     Finally, it draws the optical DTR for Arduino.
                            Screen('DrawTextures', app.w,...
                                [TexStruct.Grating, TexStruct.GaussMask, TexStruct.DtrOnMask],...
                                [srcRect', sR', sR'],...
                                [dstRect', sR', sR'], [Angle, 0, 0]);
                        else
        %                     This is the grating when the optical DTR has to be
        %                     black.
                            
                            Screen('DrawTextures', app.w,...
                                [TexStruct.Grating, TexStruct.GaussMask, TexStruct.DtrOffMask],...
                                [srcRect', sR', sR'],...
                                [dstRect', sR', sR'], [Angle, 0, 0]);
                        end
                        
        %                 Flip 'waitframes' monitor refresh intervals after last redraw.
        %                 Providing this 'when' timestamp allows for optimal timing
        %                 precision in stimulus onset, a stable animation framerate and at
        %                 the same time allows the built-in "skipped frames" detector to
        %                 work optimally and report skipped frames due to hardware
        %                 overload:
                        vbl = Screen('Flip', app.w, vbl + (waitframes - 0.5) * app.ifi);
                        
        %                 ENRICO. Removed.
        %                 % Abort function if any key is pressed:
        %                 if KbCheck
        %                     break
        %                 end
                        ii=ii+1;
                    end
                    timestamps(2*i) = vbl; % The last vbl acquired.
                    Screen('FillRect', app.w, BaselineColor, BaselineScreen);
                    Screen('Flip', app.w);
                    i=i+1;
                end
%                 Is closing the textures really necessary?
                Screen('Close',TexStruct.Grating)
                Screen('Close',TexStruct.GaussMask)
                Screen('Close',TexStruct.DtrOnMask)
                Screen('Close',TexStruct.DtrOffMask)
%--------------------------------------------------------------------------
            catch ME
                warning(ME.message)
            end %try..catch..
            
            if app.OneScreenMode_ck.Value
                CloseScreen(app)
            end
            
%             Log writing.
            timestamps = timestamps - timZero;
%             Timestamps are stored in the log file as a character vector,
%             in which timestamps are separated by a semi-colon.
%             Timestamps are saved with 6 significant digits after decimal point (microseconds).
            app.CurrentLogStruct.T.Timestamps(1) = strjoin(arrayfun(@(x) num2str(x,'%.6f'),timestamps,'un' ,0),';');
            % Write the table to the log file for this stimulation.
            writetable(app.CurrentLogStruct.T,app.CurrentLogStruct.logName)
            
%             Enables all "Go" buttons
            EnableGoBtn(app,1)
        end

        % Button pushed function: OpenScreenBtn
        function OpenScreenBtn_Callback(app, event)
            switch app.ScreenOpenFlag
                case 0 % Screen in not open
                    if app.UdpConnectionFlag
                        msg = ScreenParameterMsg(app);
                        SendUdpMsg(app,msg,app.TimeoutTime.Value)
                        return
                    end
                    
                    if app.CalibratedButton.Value
                        background = Lumi2Escher(app,app.StandbyL.Value);
                    else
                        background = app.StandbyL.Value;
                    end
                    
                    % screenRect is a 4 element array xmin, ymin, xmax, ymax
                    % strangely, xmin and ymin are set to zero. Bho?
                    OpenScreen(app, background)
                    
                    % Remember to set the fields relative to the screen size on the GUI and the
                    % relative app.variable.
                    app.WidthPix.Value = app.screenRect(3);
                    app.HeightPix.Value = app.screenRect(4);
                    
                    app.OpenScreenBtn.Text = 'Close Screen';
                    
                    app.ScreenOpenFlag = 1;
                    
%                     Call the standby button.
                    RefreshStByButtonPushed(app, [])
                case 1 % Screen is open
                    CloseScreen(app)
                    app.OpenScreenBtn.Text = 'Open Screen';
                    app.ScreenOpenFlag = 0;
                    EnableGoBtn(app,1)
            end
            
        end

        % Value changed function: SliderOrientation
        function SliderOrientation_Callback(app, event)
            app.GridOrientation.Value = app.SliderOrientation.Value;
        end

        % Button pushed function: calblack_btn
        function calblack_btn_Callback(app, event)
            if app.UdpConnectionFlag
                msg=strjoin([{app.MasterMac.Value},{'calibration'},{'1'}],',');
                SendUdpMsg(app,msg,app.TimeoutTime.Value);
                return
            end
            
            % Starts the OpenGL session if in single screen mode
            if app.OneScreenMode_ck.Value
                background = app.StandbyL.Value;
                OpenScreen(background)
            end
            dtrRect = DtrRectCalc(app);
            BaselineColor = cast([[app.white;app.white;app.white], [0;0;0]], app.ScreenBitDepth);
            BaselineScreen = [app.screenRect; dtrRect]';
            
            Screen('FillRect', app.w, BaselineColor, BaselineScreen);
            Screen('Flip', app.w);
        end

        % Button pushed function: calwhite_btn
        function calwhite_btn_Callback(app, event)
            if app.UdpConnectionFlag
                msg=strjoin([{app.MasterMac.Value},{'calibration'},{'2'}],',');
                SendUdpMsg(app,msg,app.TimeoutTime.Value);
                return
            end
        
            % Fill the screen with white
            if app.OneScreenMode_ck.Value
                background = app.StandbyL.Value;
                OpenScreen(background)
            end
            
            dtrRect = DtrRectCalc(app);
            BaselineColor = cast([[0;0;0], [app.white;app.white;app.white]], app.ScreenBitDepth);
            BaselineScreen = [app.screenRect; dtrRect]';

            Screen('FillRect', app.w, BaselineColor, BaselineScreen);
            Screen('Flip', app.w);
        end

        % Value changed function: FlashFastN
        function FlashFastN_Callback(app, event)
            FlashFastMat = app.FlashFastTable.Data;
            if app.FlashFastN.Value > size(FlashFastMat,1)
                app.FlashFastN.Value = size(FlashFastMat,1);
            end
            setnumber = app.FlashFastN.Value;
            
%             Set the flash luminance
            app.FSflashL.Value = FlashFastMat(setnumber,2);
%             Call the corresponding callback
            FSflashLValueChanged(app,[])
            
%             Set the baseline luminance
            app.FSbaselineL.Value = FlashFastMat(setnumber,3);
%             Call the corresponding callback
            FSbaselineLValueChanged(app,[])
        end

        % Close request function: EscherMasterFig
        function EscherMasterFigCloseRequest(app, event)
            try
            % save parameters in an autosettings file
    %             saveParams(app, [app.escherAppFolder '\autosettings.mat'])
                if isvalid(app.UdpTimeoutTimer)
                    stop(app.UdpTimeoutTimer)
                end
                delete(app.UdpTimeoutTimer)
                CloseAllUdp(app)
            catch ME
%                 warning('off','backtrace') % maybe not necessary
                warning(ME.message)
            end
            delete(app)
        end

        % Button pushed function: LoadScreenFileButton
        function LoadScreenFileButtonPushed(app, event)
            [file,path] = uigetfile({'*.dat;*.csv;*.txt',...
                'Text files (*.dat,*.csv,*.txt)';...
                '*.*',  'All Files (*.*)'}, 'Select a File');
            if file==0
                return
            end
            
            T = readtable([path, file]);
            
            app.ScreenNameTxt.Value = T.name{1};
            app.ScreenWidth.Value = T.width(1);
            app.ScreenHeight.Value = T.height(1);
            app.ScreenFuncTxt.Value = T.formula{1};
            app.ScreenNotesTxt.Value = T.notes{1};
            
            SetScreenSizeAngle(app)
        end

        % Button pushed function: RefreshStBy
        function RefreshStByButtonPushed(app, event)
            if app.UdpConnectionFlag
                msg=strjoin([{app.MasterMac.Value},{'background'},{num2str(app.StandbyL.Value)}],',');
                SendUdpMsg(app,msg,app.TimeoutTime.Value);
                return
            end
            
            ard_flag = app.ard_ck.Value;
            Blumi = app.StandbyL.Value;
            if app.CalibratedButton.Value
                Blumi = Lumi2Escher(app,Blumi);
            end
            Blumi = app.white*Blumi;
            if ard_flag
                dtrRect = DtrRectCalc(app);
                BaselineColor = cast([[Blumi;Blumi;Blumi], [0;0;0]], app.ScreenBitDepth);
                cellRects = [app.screenRect; dtrRect]';
                %     This is commented because in flashes, the optical DTR
                %     duration is the same as the flash duration
                %     optDtrTime = app.optDtrTimeTxt.Value/1000; % optical DTR duration in s
            else
                BaselineColor = cast(Blumi, app.ScreenBitDepth);
               	cellRects = app.screenRect;
            end
            Screen('FillRect', app.w, BaselineColor, cellRects); % paints the rectangle (entire screen)
            Screen('Flip', app.w);
        end

        % Button pushed function: FlashFastLoadTable
        function FlashFastLoadTableButtonPushed(app, event)
            %             Default file types to be read.
            filter = {'*.txt;*.dat;*.csv','Delimited text (*.txt,*.dat,*.csv)';...
                '*.*',  'All Files (*.*)'};
            [file,path] = uigetfile(filter, 'Select file to open','FlashStimuliTable.csv');
            if file==0
                return
            end
            StimTable = readtable([path, file]);
            app.FlashFastTable.Data = StimTable;
        end

        % Button pushed function: CheckGo
        function GoCheck_Callback(app, event)
            % Nov 25, 2013
            % the function has been modified to allow the display of a periodically
            % alternating checkboard.
            % Blumi and Slumi define the luminance of the black and light squares.
            % The spatial frequency is defined by the "spatial frequency" field in the
            % grid generation module.
            % p define the period of the alternation (ON and OFF)
            % Bt defines the baseline_txt spent in the baseline_txt equiluminant grey computed
            % as (Blumi+Slumi)/2 if a calibration LUT is active.
            
            % May 2017.
            % This code has been modified to allow the creation of the checkerboad stim
            % on the VR googles.
            
            %             Disables all "Go" buttons
            EnableGoBtn(app,0)
            
            % load parameters in local variables
            Blumi = app.CheckLumiB.Value;
            Slumi = app.CheckLumiW.Value;
            %             gray luminance for equiluminant background.
            Glumi = app.CheckLumiMean.Value;
            sF = app.spatialFrequency.Value;
            Bt = app.initialDelay.Value;
            p = app.CheckPeriod.Value/2; % hemi-period. p is the time between a reversal and the next (2 reversals = 1 period)
            n = app.CheckRepetitions.Value;                % repetitions
            
            % Writing the log file.
            app.runNumber = readmatrix(app.runNumberFile);
            runString = sprintf('%04d', app.runNumber);
            app.CurrentLogStruct.logName = [app.logDir, runString, '.check', '.dat'];
%             Initial writing of the file. The file contains the whole
%             table, but the last column (the timestamps) is empty.
            app.CurrentLogStruct.T = table('Size',[1, 10],'VariableTypes',[{'datetime'},...
                repmat({'double'},[1, 8]),{'string'}],'VariableNames',...
                {'StartTime','Blumi','Slumi','MeanLumi','K','sF', 'Bt','p','n','Timestamps'});
            app.CurrentLogStruct.T.StartTime(1) = datetime;
            app.CurrentLogStruct.T{1,2:end-1} = [Blumi,Slumi,Glumi,app.CheckContrast.Value,...
                sF,Bt,p,n];
            % Write the table to the log file for this stimulation.
            writetable(app.CurrentLogStruct.T,app.CurrentLogStruct.logName)
%             Updates the runNumber file.
            app.runNumber = app.runNumber +1;
            writematrix(app.runNumber, app.runNumberFile)
            
            if app.UdpConnectionFlag
                msg=strjoin([{app.MasterMac.Value},{'check'},arrayfun(@num2str,[Blumi,Slumi,sF,Bt,p,n],'un',0)],',');
                SendUdpMsg(app,msg,app.TimeoutTime.Value)
                return
            end
            
            % Starts the OpenGL session if in single screen mode
            if app.OneScreenMode_ck.Value == 1
                OpenScreen(app,app.CheckLumiMean.Value);
            end
            
            ard_flag = app.ard_ck.Value;
            oculusFlag = app.oculusCk.Value;
            
            % The checker board is generated by filling the entire screenRect with
            % squares. Two different "color arrays" are prepared for the
            % alternating pattern. The size of the squares is prepared according to
            % the spatial frequency defined in the grid GUI.
            % Synchronisation signals are on the DTR output are generated at the beginning of the sequence.
            % This is followed by a equiluminant grey of duration "Baseline". After
            % that the alternating stims start with period (one full on-off
            % transition) given by the term "Period".
            if app.CalibratedButton.Value
                Blumi = Lumi2Escher(app,Blumi);
                Slumi = Lumi2Escher(app,Slumi);
                Glumi = Lumi2Escher(app,Glumi);
            end
                        
            % Next lines: insert the LUT correction
            Blumi = app.white*Blumi;             % Luminance expressed as fractions of 'white'
            Slumi = app.white*Slumi;
            Glumi = app.white*Glumi;
            
%             Angle of a single pixel
            OnePxAngle = PixelAngleCalc(app);
%             The spatial frequency of the stimulus in cycles/pixel is
%             calculated.
            f = OnePxAngle*sF;
%             Number of pixels for one spatial cycle of stimlulation
%             (rounded up to integer).
            pixSize = ceil(1/f);
                        
            % compute the checkerboard_ck and the associate "colour" arrays
            side=pixSize/2;
            % screenRect contains the screen coordinates. x  y min (0 and 0,
            % strangely) and then x max and y max
            % If the oculusMode flag is true than the stimulu is prepared to be
            % displaied on the VR goggles. The screen width is divided in half
            % and two identical and aligned checkerboards are presented on each
            % screen
            if oculusFlag
                halfWidth = app.screenRect(3)/2;
                nSquareX = ceil((halfWidth/side));
            else
                nSquareX = ceil(app.screenRect(3)/side);
            end
            nSquareY = ceil(app.screenRect(4)/side);
            
            %             the square row must be odd in order to have a correct
            %             alternation of on and off squares.
            nSquareX = nSquareX - mod(nSquareX,2) + 1; %Makes the number odd by adding 1 if it's even
            % the square row must be odd in order to have a correct
            % alternation of on and off squares.
            
            % Total area (in pixels) occupied by the checkerboard_ck. If this is larger than
            % the screen it is cropped on the right and bottom sides.
            % if in "oculus mode" the crop is performed on the left side
            %        if oculusFlag,
            % checkerW = side*nSquareX;
            % winRectAdapted= [0 0 halfWidth side*nSquareY];
            %        else
            winRectAdapted= [0 0 side*nSquareX side*nSquareY];
            %        end
            ntot = nSquareX*nSquareY;
            % Create the tiling squares stored in the array cellRects.
            % "ArrangeRects" is a function provided by the toolbox.
            % cellRects contains the rectangle coordinates. Two screen buffer are
            % created: one with the "white" stim and one with the "black".
            % Then, at the correct timing the screens are flipped.
            
            cellRects = ArrangeRects(ntot,[0 0 2 2],winRectAdapted);
            
            %             If the Oculus Flag is on, cellRects will be changed so that
            %             the second screen will have the same stimuli (necessary?)
            if oculusFlag
                %                 now double the cellRects array... to do that 2 words about
                %                 its structure: it contains the list of the xmin ymin xmax ymax
                %                 coodinates of the checkers
                checkerW = side*nSquareX;
                
                %                 now we build the second half of the screen by copying the
                %                 left half plus the half width of the screen
                cellRects2 = cellRects;
                %                 halfWidth is added to the first and third column
                cellRects2(:,[1 3]) = cellRects(:,[1 3]) + halfWidth;
                
                cellRects = [CellRects, CellRects2]';
            end
            
            cellColor1On = ones(3,ntot, app.ScreenBitDepth) .* Slumi;
            cellColor2On = zeros(3,ntot, app.ScreenBitDepth) + Blumi;
            cellColor1On(1:3,1:2:ntot) = Blumi;
            cellColor2On(1:3,1:2:ntot) = Slumi;
            
            
            %             New part. Enrico 2019/05/24
            if ard_flag
                dtrRect = DtrRectCalc(app);
                optDtrTime = app.optDtrTimeTxt.Value/1000; % optical DTR duration in s
                
                BaselineColor = cast([[Glumi;Glumi;Glumi], [0;0;0]], app.ScreenBitDepth);
                BaselineRect = [app.screenRect; dtrRect]';
                
                cellColor1Off = [cellColor1On, [0; 0; 0]];
                cellColor2Off = [cellColor2On, [0; 0; 0]];
                cellColor1On = [cellColor1On, [app.white; app.white; app.white]];
                cellColor2On = [cellColor2On, [app.white; app.white; app.white]];
                cellRects = [cellRects; dtrRect]';
            else
                BaselineColor = cast(Glumi, app.ScreenBitDepth);
                BaselineRect = app.screenRect;
            end
            
%             This vector contains two timestamps for each period, one for
%             each checkerboard reversal.
            timestamps = NaN(1,2*n);
            % load the WaitSecs function
            WaitSecs (0);
            
%             ACTUAL START OF STIMULATION
            try
                %                 Fill screen with the baseline equiluminant gray
                %                 OK, let's start. First thing first: edge out and wait for end of baseline
                %                 if handles.SerialSynch == 1     % Out an edge on the DTR line to synchronize the sweep beginning
                %                 Synch by Kaboum is performed on the rising edge
                %  ComHandle.DataTerminalReady = 'on';   comments to get rid of
                %  the initial trigger!
                %  timA = WaitSecs (0.002);
                %  ComHandle.DataTerminalReady = 'off';
                %        end
                % Remember to check data analysis. Synch on falling edge
                % The following statement takes about 0.0009 sec on the VAIO
                Screen('FillRect', app.w, BaselineColor, BaselineRect)     % paints the rectangle (entire screen)
                Screen('Flip', app.w);  % with equiluminant gray.
                i=1;
%                 Let's acquire the starting time.
                timZero = WaitSecs(0);
%               --- Prepare first stimulus (from baseline to cellColor1)
                Screen('FillRect', app.w, cellColor1On, cellRects)
                while i <= n
%                     It used to be:
%                 while ~KbCheck && i<=n
                    FirstTim = timZero+Bt+2*(i-1)*p; % remember: p is the half of the period!
                    timestamps(2*i-1) = Screen('Flip', app.w, FirstTim);
%                     if app.SerialSynch == 1     % Out an edge on the DTR line to synchronize the sweep beginning
%                         % give out a rising edge at the beginning of the sweep to synchronize clampex
%                         ComHandle.DataTerminalReady = 'on';
%                         timA = WaitSecs (0.002);
%                         ComHandle.DataTerminalReady = 'off';
%                     end
                    
%                     --- Screen flip to terminate optical DTR
                    if ard_flag
                        Screen('FillRect',app.w,cellColor1Off,cellRects)
                        %                         cellColor1On stays on for "optDtrTime" seconds; arduino must wait more than that.
                        Screen('Flip', app.w, FirstTim+optDtrTime);
                    end
                    
%                     --- Second stimulus (from cellColor1 to cellColor2)
                    SecondTim = timZero+Bt+(2*i-1)*p; % remember: p is the half of the period!
                    Screen('FillRect',app.w,cellColor2On,cellRects);
                    timestamps(2*i) = Screen('Flip', app.w, SecondTim);
%                     if app.SerialSynch == 1
%                         ComHandle.DataTerminalReady = 'on';
%                         timA = WaitSecs (0.002);
%                         ComHandle.DataTerminalReady = 'off';
%                     end
                    
%                     --- Screen flip to terminate optical DTR
                    if ard_flag
                        Screen('FillRect', app.w,cellColor2Off,cellRects)
                        Screen('Flip', app.w, SecondTim+optDtrTime); %colOn1 stays on for "optDtrTime" s; arduino must wait more than that.
                    end
                    %---
                    
%                     --- Prepare first stimulus (from cellColor2 to cellColor1)
                    Screen('FillRect', app.w,cellColor1On,cellRects)
                    i=i+1;
                end
                
                Screen('FillRect', app.w, BaselineColor, BaselineRect)     % paints the rectangle (entire screen)
                Screen('Flip', app.w);                           % with equiluminant gray.
                
                if app.OneScreenMode_ck.Value == 1
                    CloseScreen(app)
                end
            catch ME
                warning(ME.message)
            end
            
            if app.OneScreenMode_ck.Value == 1
                CloseScreen(app)
            end
            
%             Log writing.
            timestamps = timestamps - timZero;
%             Timestamps are stored in the log file as a character vector,
%             in which timestamps are separated by a semi-colon.
%             Timestamps are saved with 6 significant digits after decimal point (microseconds).
            app.CurrentLogStruct.T.Timestamps(1) = strjoin(arrayfun(@(x) num2str(x,'%.6f'),timestamps,'un' ,0),';');
            % Write the table to the log file for this stimulation.
            writetable(app.CurrentLogStruct.T,app.CurrentLogStruct.logName)
            
            %             Enables all "Go" buttons
            EnableGoBtn(app,1)
        end

        % Value changed function: CheckFastN
        function CheckFastNValueChanged(app, event)
            CheckFastMat = app.CheckFastTable.Data;
            if app.CheckFastN.Value > size(CheckFastMat,1)
                app.CheckFastN.Value = size(CheckFastMat,1);
            end
            setnumber = app.CheckFastN.Value;
            
%             Set the flash luminance
            app.CheckContrast.Value = CheckFastMat(setnumber,2);
%             Set the baseline luminance
            app.CheckLumiMean.Value = CheckFastMat(setnumber,3);
            
            CheckUpdateLumi(app,0)
        end

        % Button pushed function: CheckFastLoadTable
        function CheckFastLoadTableButtonPushed(app, event)
            %             Default file types to be read.
            filter = {'*.txt;*.dat;*.csv','Delimited text (*.txt,*.dat,*.csv)';...
                '*.*',  'All Files (*.*)'};
            [file,path] = uigetfile(filter, 'Select file to open','CheckStimuliTable.csv');
            if file==0
                return
            end
            StimTable = readtable([path, file]);
            app.CheckFastTable.Data = StimTable;
        end

        % Selection changed function: CalModeRBGroup
        function CalModeRBGroupSelectionChanged(app, event)
%             Depending on whether the calibration is selected or not, the
%             unit of measurement of the luminance is changed.
            if app.CalibratedButton.Value
%                 If the calibration is there, we need the luminance to be
%                 expressed as cd/m2
                mystring = 'cd/m²';
            else
%                 Otherwise, we set it to be (0-1)
                mystring = '(0-1)';
            end
            
            set(app.LabeLumiVector, 'Text', mystring)
        end

        % Value changed function: CheckLumiB
        function CheckLumiBValueChanged(app, event)
            CheckUpdateLumi(app,1)
        end

        % Value changed function: CheckLumiW
        function CheckLumiWValueChanged(app, event)
            CheckUpdateLumi(app,1)
        end

        % Value changed function: ScreenWidth
        function ScreenWidthValueChanged(app, event)
            SetScreenSizeAngle(app)
        end

        % Value changed function: ScreenHeight
        function ScreenHeightValueChanged(app, event)
            SetScreenSizeAngle(app)
        end

        % Value changed function: CheckLumiMean
        function CheckLumiMeanValueChanged(app, event)
            CheckUpdateLumi(app,0)
        end

        % Value changed function: CheckContrast
        function CheckContrastValueChanged(app, event)
            CheckUpdateLumi(app,0)
        end

        % Value changed function: GridOrientation
        function GridOrientationValueChanged(app, event)
            app.SliderOrientation.Value = app.GridOrientation.Value;
        end

        % Value changed function: FSbaselineL
        function FSbaselineLValueChanged(app, event)
            if app.CalibratedButton.Value
                app.FSbaselineLEsch.Value = Lumi2Escher(app,app.FSbaselineL.Value);
            end
        end

        % Value changed function: FSflashL
        function FSflashLValueChanged(app, event)
            if app.CalibratedButton.Value
                app.FSflashLEsch.Value = Lumi2Escher(app, app.FSflashL.Value);
            end
        end

        % Value changed function: OrientFastN
        function OrientFastNValueChanged(app, event)
            OrientFastMat = app.OrientFastTable.Data;
            if app.OrientFastN.Value > size(OrientFastMat,1)
                app.OrientFastN.Value = size(OrientFastMat,1);
            end
            setnumber = app.OrientFastN.Value;
            
%             Set the orientation
            app.GridOrientation.Value = OrientFastMat(setnumber,2);
            GridOrientationValueChanged(app,[])
        end

        % Value changed function: FlashFastTotN
        function FlashFastTotNValueChanged(app, event)
            StimTotN = app.FlashFastTotN.Value;
            TableSize = size(app.FlashFastTable.Data,1);
            
            if StimTotN > TableSize
%             A number of rows are added to the table.
                if app.logspacedFlashCk.Value && app.includezeroFlashCk.Value
                    minL = 0;
                else
                    minL = app.FlashFastMinL.Value;
                end
%                 The extra rows are filled with a blank stimulus.
                app.FlashFastTable.Data = vertcat(app.FlashFastTable.Data,...
                    horzcat((TableSize+1:StimTotN)', repmat(minL,StimTotN-TableSize,2)));
            else
%                 The table is shortened.
                app.FlashFastTable.Data = app.FlashFastTable.Data(1:StimTotN,:);
            end
        end

        % Button pushed function: RandomizeButton
        function RandomizeButtonPushed(app, event)
%             If the table is empty, this function is just executed. If
%             it's not empty, a confirmation will be prompted.
            if ~isempty(app.ExperimentTable.Data)
                answ = uiconfirm(app.EscherMasterFig, 'This will delete previously generated table. Do you confirm?', 'Warning');
                switch answ
                    case 'Cancel'
                        return
                end
            end
            
%             Get some dimensions.
            SessionLength = app.ExpListFlashCk.Value*size(app.FlashFastTable.Data,1)+...
                app.ExpListCheckCk.Value*size(app.CheckFastTable.Data,1)+...
                app.ExpListGratingsOrientCk.Value*size(app.OrientFastTable.Data,1);
            tableN = SessionLength * app.ExpListSessionN.Value;
            app.ExperimentTable.Data = cell(tableN,4);
            StartNum = app.ExpListFirstNum.Value;
            
            for i = 1:app.ExpListSessionN.Value
                if app.ExpListFlashCk.Value
                    FlashTmp = cell(size(app.FlashFastTable.Data,1), 2);
                    FlashTmp(:,1) = repmat({'flash'},size(app.FlashFastTable.Data,1),1);
                    FlashTmp(:,2) = num2cell(1:size(app.FlashFastTable.Data,1));
                else
                    FlashTmp = [];
                end
                
                if app.ExpListCheckCk.Value
                    CheckerTmp = cell(size(app.CheckFastTable.Data,1), 2);
                    CheckerTmp(:,1) = repmat({'checkerboard'},size(app.CheckFastTable.Data,1),1);
                    CheckerTmp(:,2) = num2cell(1:size(app.CheckFastTable.Data,1));
                else
                    CheckerTmp = [];
                end
                
                if app.ExpListGratingsOrientCk.Value
                    GridTmp = cell(size(app.OrientFastTable.Data,1), 2);
                    GridTmp(:,1) = repmat({'grid'},size(app.OrientFastTable.Data,1),1);
                    GridTmp(:,2) = num2cell(1:size(app.OrientFastTable.Data,1));
                else
                    GridTmp = [];
                end
                
                sessionMat = [FlashTmp; CheckerTmp; GridTmp];
                
                permutations = randperm(SessionLength);
                sessionMat = sessionMat(permutations,:);
                
                app.ExperimentTable.Data(1+SessionLength*(i-1) : SessionLength*i, [2 3]) = sessionMat;
            end
            
            
%             Give a number to each experiment, starting from
%             ExpListFirstNum.
            app.ExperimentTable.Data(:,1) = num2cell(StartNum : tableN+StartNum-1);
            app.ExpListCurrentIdx=1;
%             The first experiment has to start from the ExpListFirstNum.
            app.ExpListCurrentNum.Value = StartNum;
%             The first row in app.ExpListColors is the background.
            app.ExperimentTable.BackgroundColor = app.ExpListColors(1,:) .* ones(tableN,3);
%             The second row in app.ExpListColors is the current exp.
            app.ExperimentTable.BackgroundColor(1,:) = app.ExpListColors(2,:);
            
            app.ExpListGo.Enable = 1;
            
        end

        % Cell selection callback: ExperimentTable
        function ExperimentTableCellSelection(app, event)
            app.ExpListSelectedIndices = event.Indices;
            app.ExpListDeleteRows.Enable = 1;
            app.ExpListAddRows.Enable = 1;
        end

        % Button pushed function: CopytableButton
        function CopytableButtonPushed(app, event)
            % To copy the data from the table of zebrasays to the clipboard
            tablesize = size(app.ExperimentTable.Data);
            
            % Created a function to copy data to clipboard from outtable.
            % selectionIndices is a 4-element vector containing the
            % upper-left corner and the bottom-right corner indices
            SelTab2Clip(app,app.ExperimentTable,[1 1 tablesize(1) tablesize(2)])
        end

        % Button pushed function: ExpMoveUp
        function ExpMoveUpPushed(app, event)
            %             The whole selected part is moved of one row up
            SelRowIndices = [min(app.ExpListSelectedIndices(:,1)), max(app.ExpListSelectedIndices(:,1))];
            %             Execute only when the first selected row is not the first
            if SelRowIndices(1)~=1 && (SelRowIndices(1) > app.ExpListCurrentIdx+1 ||...
                    SelRowIndices(2) < app.ExpListCurrentIdx)
                %                 shift a subset of the array circularly to get to the
                %                 app.ExperimentTable.Data(SelRowIndices(1)-1 : SelRowIndices(2), 2:end) =...
                %                     circshift(app.ExperimentTable.Data(SelRowIndices(1)-1 : SelRowIndices(2), 2:end), -1, 1);
                app.ExperimentTable.Data(SelRowIndices(1)-1:SelRowIndices(2), 2:end) =...
                    app.ExperimentTable.Data([SelRowIndices(1):SelRowIndices(2), SelRowIndices(1)-1], 2:end);
                
                %                 The same must be done for colors
                %                 app.ExperimentTable.BackgroundColor(SelRowIndices(1)-1 : SelRowIndices(2), :) =...
                %                     circshift(app.ExperimentTable.BackgroundColor(SelRowIndices(1)-1 : SelRowIndices(2), :), -1, 1);
                app.ExperimentTable.BackgroundColor(SelRowIndices(1)-1 : SelRowIndices(2), :) =...
                    app.ExperimentTable.BackgroundColor([SelRowIndices(1):SelRowIndices(2), SelRowIndices(1)-1], :);
                
                %                 Then the index of current experiment has to be changed.
                %                 Also the number corresponding to current experiment is
                %                 going to change.
                if app.ExpListCurrentIdx >= SelRowIndices(1) && app.ExpListCurrentIdx <= SelRowIndices(2)
                    app.ExpListCurrentIdx = app.ExpListCurrentIdx - 1;
                    app.ExpListCurrentNum.Value = app.ExpListCurrentNum.Value - 1;
                elseif app.ExpListCurrentIdx == SelRowIndices(1) - 1
                    app.ExpListCurrentIdx = SelRowIndices(2);
                    app.ExpListCurrentNum.Value = app.ExpListCurrentNum.Value + (SelRowIndices(2)-SelRowIndices(1)+1);
                end
            end
        end

        % Button pushed function: ExpMoveDown
        function ExpMoveDownButtonPushed(app, event)
            %             The whole selected part is moved of one row up
            SelRowIndices = [min(app.ExpListSelectedIndices(:,1)), max(app.ExpListSelectedIndices(:,1))];
            %             Execute only when the first selected row is not the first
            if SelRowIndices(1)~=1
                %                 shift a subset of the array circularly to get to the
                %                 app.ExperimentTable.Data(SelRowIndices(1) : SelRowIndices(2)+1, 2:end) =...
                %                     circshift(app.ExperimentTable.Data(SelRowIndices(1) : SelRowIndices(2)+1, 2:end), 1, 1);
                app.ExperimentTable.Data(SelRowIndices(1):SelRowIndices(2)+1, 2:end) = ...
                    app.ExperimentTable.Data([SelRowIndices(2)+1, SelRowIndices(1):SelRowIndices(2)], 2:end)
                
                %                 The same must be done for colors
                app.ExperimentTable.BackgroundColor(SelRowIndices(1) : SelRowIndices(2)+1, :) =...
                    circshift(app.ExperimentTable.BackgroundColor(SelRowIndices(1) : SelRowIndices(2)+1, :), 1, 1);
                
                %                 Then the index of current experiment has to be changed.
                %                 Also the number corresponding to current experiment is
                %                 going to change.
                if app.ExpListCurrentIdx >= SelRowIndices(1) && app.ExpListCurrentIdx <= SelRowIndices(2)
                    app.ExpListCurrentIdx = app.ExpListCurrentIdx + 1;
                    app.ExpListCurrentNum.Value = app.ExpListCurrentNum.Value + 1;
                elseif app.ExpListCurrentIdx == SelRowIndices(2) + 1
                    app.ExpListCurrentIdx = SelRowIndices(1);
                    app.ExpListCurrentNum.Value = app.ExpListCurrentNum.Value + (SelRowIndices(2)-SelRowIndices(1)+1);
                end
            end
        end

        % Button pushed function: ExpListDeleteRows
        function ExpListDeleteRowsButtonPushed(app, event)
            answ = uiconfirm(app.EscherMasterFig,'This will delete all the selected columns','Warning','Options',{'Delete','Cancel'});
            if strcmp(answ,'Delete')
                SelRowIndices = [min(app.ExpListSelectedIndices(:,1)), max(app.ExpListSelectedIndices(:,1))];
                app.ExperimentTable.Data(SelRowIndices(1):SelRowIndices(2),:) = [];
                app.ExperimentTable.BackgroundColor(SelRowIndices(1):SelRowIndices(2), : ) = [];
                app.ExperimentTable.Data(:,1) = num2cell(app.ExpListFirstNum.Value : ...
                    size(app.ExperimentTable.Data, 1)+app.ExpListFirstNum.Value-1);
                
%                 If the current experiment is in the selected rows
                if app.ExpListCurrentIdx >= SelRowIndices(1) && app.ExpListCurrentIdx <= SelRowIndices(2)
%                     If current experiment is not the last row
                    if app.ExpListCurrentIdx < size(app.ExperimentTable.Data, 1)
%                         The index of the first deleted row becomes the
%                         index of the current experiment.
                        app.ExpListCurrentIdx = SelRowIndices(1);
                        app.ExperimentTable.BackgroundColor(app.ExpListCurrentIdx,:) = app.ExpListColors(2,:);
%                         If the current experiment is the last row OR if
%                         the selection encompassing the current
%                         experiment also reaches the end of the table.
                    elseif app.ExpListCurrentIdx == size(app.ExperimentTable.Data, 1) ||...
                            SelRowIndices(2) == size(app.ExperimentTable.Data, 1)
                        app.ExpListCurrentIdx = [];
                        app.ExpListGo.Enable = 0;
                    end
%                     If the selected rows are before the current
%                     experiment.
                elseif app.ExpListCurrentIdx > SelRowIndices(2)
                    app.ExpListCurrentIdx = app.ExpListCurrentIdx - (SelRowIndices(2)-SelRowIndices(1)+1);
                end
                app.ExpListCurrentNum.Value = app.ExperimentTable.Data{app.ExpListCurrentIdx,1};
                app.ExpListDeleteRows.Enable = 0;
                app.ExpListAddRows.Enable = 0;
            end
        end

        % Button pushed function: ExpListGo
        function ExpListGoPushed(app, event)
%             Current Index is the index of the current experiment.
            currIdx = app.ExpListCurrentIdx;
%             Current FastNumber is the number of the current experiment on
%             the table (it's the same as Current Index ONLY if the
%             Experiment Table starts from experiment n. 1.
            currFastNum = app.ExperimentTable.Data{currIdx,3};
            
%             If the pupil preview while acquiring is checked
            if app.PupilWhileStimulating.Value && app.UdpConnectionFlag
                StartPreviewButtonPushed(app, [])
            end
            
            switch app.ExperimentTable.Data{currIdx,2}
                case 'flash'
%                     Changes the stimulus
                    app.FlashFastN.Value = currFastNum;
                    FlashFastN_Callback(app,[])
                    
%                     The current row turns of the third color (yellow)
                    app.ExperimentTable.BackgroundColor(currIdx,:) = app.ExpListColors(3,:);
                    
%                     Starts the stimulation
                    GoFlash_Callback(app,[])
                case 'checkerboard'
%                     Changes the stimulus
                    app.CheckFastN.Value = currFastNum;
                    CheckFastNValueChanged(app,[])
                    
%                     The current row turns of the third color (yellow)
                    app.ExperimentTable.BackgroundColor(currIdx,:) = app.ExpListColors(3,:);
                    
%                     Starts the stimulation
                    GoCheck_Callback(app,[])
                case {'grid','grating'}
%                     Changes the stimulus
                    app.OrientFastN.Value = currFastNum;
                    OrientFastNValueChanged(app,[])
                    
%                     Set maximum contrast
                    app.CheckFastN.Value = size(app.CheckFastTable.Data,1);
                    CheckFastNValueChanged(app,[])
                    
%                     The current row turns of the third color (yellow)
                    app.ExperimentTable.BackgroundColor(currIdx,:) = app.ExpListColors(3,:);

%                     Starts the stimulation
                    OrientFastNValueChanged(app,[])
                    GoGrid_Callback(app,[])
            end
            
%             The current row turns of the fourth color (green)
            app.ExperimentTable.BackgroundColor(currIdx,:) = app.ExpListColors(4,:);
            
%             Updates the index of the new current exp and it's color in
%             the table.
            if currIdx ~= size(app.ExperimentTable.Data,1)+1
                currIdx = currIdx + 1;
                app.ExperimentTable.BackgroundColor(currIdx,:) = app.ExpListColors(2,:);
            else
                currIdx=[];
            end
            app.ExpListCurrentIdx = currIdx;
            
%             Updates the current number shown in the GUI.
            app.ExpListCurrentNum.Value = app.ExpListCurrentNum.Value + 1;
        end

        % Button pushed function: ExpListAddRows
        function ExpListAddRowsButtonPushed(app, event)
            if isempty(app.ExperimentTable.Data)
                app.ExperimentTable.Data = cell(1,length(app.ExperimentTable.ColumnEditable));
                app.ExperimentTable.BackgroundColor(1,:) = app.ExpListColors(2,:);
                app.ExpListCurrentIdx = 1;
                app.ExpListCurrentNum.Value = 1 + app.ExpListFirstNum.Value;
            else
%                 The new row is added just below the last selected row.
                AddedRowIdx = max(app.ExpListSelectedIndices(:,1))+1;
%                 Add an empty cell at the end of the table.
                app.ExperimentTable.Data = [app.ExperimentTable.Data; cell(1, size(app.ExperimentTable.Data,2))];
%                 And then moves it to the position of the added row.
                app.ExperimentTable.Data(AddedRowIdx:end,2:end) = app.ExperimentTable.Data([end,AddedRowIdx:end-1],2:end);
    %             Updates the number (column n. 1) of the last row
                app.ExperimentTable.Data{end,1} = app.ExperimentTable.Data{end-1,1} + 1;
%                 Then the color of the table is updated too.
                app.ExperimentTable.BackgroundColor = [app.ExperimentTable.BackgroundColor; app.ExpListColors(1,:)];
%                 And the color is moved to the position of the added row.
                app.ExperimentTable.BackgroundColor(AddedRowIdx:end,:) = app.ExperimentTable.BackgroundColor([end,AddedRowIdx:end-1],:);
                
%                 Then the color must be updated.
                if AddedRowIdx < app.ExpListCurrentIdx
%                     If the index of the added row is the current
%                     experiment, the added row is green.
                    app.ExperimentTable.BackgroundColor(AddedRowIdx,:) = app.ExpListColors(4,:);
%                     And the current experiment index and number is
%                     changed by one.
                    app.ExpListCurrentIdx = app.ExpListCurrentIdx + 1;
                    app.ExpListCurrentNum.Value = app.ExpListFirstNum.Value + 1;
                elseif AddedRowIdx == app.ExpListCurrentIdx && AddedRowIdx < size(app.ExperimentTable.Data,1)
%                     If the position of the added row is just before the
%                     current experiment, this added row becomes the new
%                     current experiment.
                    app.ExperimentTable.BackgroundColor(AddedRowIdx,:) = app.ExpListColors(2,:);
%                     The current experiment number and index is not
%                     changed.
%                     The previous current experiment row color must become
%                     white.
                    app.ExperimentTable.BackgroundColor(AddedRowIdx+1,:) = app.ExpListColors(1,:);
                elseif AddedRowIdx == size(app.ExperimentTable.Data,1) && isempty(app.ExpListCurrentIdx)
                    app.ExperimentTable.BackgroundColor(AddedRowIdx,:) = app.ExpListColors(2,:);
                    app.ExpListCurrentIdx = AddedRowIdx;
                    app.ExpListCurrentNum.Value = AddedRowIdx + app.ExpListFirstNum.Value;
                end
            end
            
        end

        % Value changed function: ExpListFirstNum
        function ExpListFirstNumValueChanged(app, event)
            StartNum = app.ExpListFirstNum.Value;
            app.ExperimentTable.Data(:,1) = num2cell(StartNum : size(app.ExperimentTable.Data,1)+StartNum-1);
        end

        % Button pushed function: RefreshDevicesButton
        function RefreshDevicesButtonPushed(app, event)
            refreshcamera(app)
        end

        % Value changed function: CameraDropDown
        function CameraDropDownValueChanged(app, event)
            app.selcamIdx = find(strcmp(app.CameraDropDown.Value,app.CameraDropDown.Items),1);
            selectcam(app)
        end

        % Value changed function: ImageSizeDropDown
        function ImageSizeDropDownValueChanged(app, event)
            app.selformatIdx = find(strcmp(app.ImageSizeDropDown.Value,app.ImageSizeDropDown.Items),1);
            selectformat(app)
        end

        % Value changed function: 
        % NumberofPointsinPreviewGraphEditField
        function NumberofPointsinPreviewGraphEditFieldValueChanged(app, event)
            app.PupilStruct.previewgraph_size = app.NumberofPointsinPreviewGraphEditField.Value;
        end

        % Value changed function: RatefpsEditField
        function RatefpsEditFieldValueChanged(app, event)
            app.PupilStruct.previewRate = app.RatefpsEditField.Value;
        end

        % Button pushed function: ResetAxesButton
        function ResetAxesButtonPushed(app, event)
            app.Image1UIAxes.CameraUpVectorMode = 'manual';
            app.Image1UIAxes.CameraUpVector = [0 1 0];
        end

        % Button pushed function: StartPreviewButton
        function StartPreviewButtonPushed(app, event)
            switch app.PupilStruct.preview_status
                case 0
                    % Initialize the preview plot
                    PreviewInitialize(app)
                    
                    app.StartPreviewButton.Text = 'Stop Preview';
                    app.PupilStruct.preview_status = 1;
                case 1
                    PreviewStop(app)

                    app.StartPreviewButton.Text = 'Start Preview';
                    app.PupilStruct.preview_status = 0;
            end
        end

        % Value changed function: DurationsEditField
        function DurationsEditFieldValueChanged(app, event)
            app.PupilStruct.acqDuration = app.DurationsEditField.Value;
        end

        % Button pushed function: PupilStartAcquisitionButton
        function PupilStartAcquisitionButtonPushed(app, event)
            switch app.PupilStruct.acqStatus % Status of the button
                case 0
                    
%                     If there is already a preview open, it is closed.
                    if app.PupilStruct.preview_status
                        StartPreviewButtonPushed(app, [])
                    end
                    
                    % Remove any acquisition timer
                    delete(app.PupilStruct.AcquisitionTimer)
                    
%                     Checking if the specified folder where to save the
%                     files is there. If it's not there, then it is
%                     created.
                    if  exist(app.SaveFolderEditField.Value, 'dir') ~= 7 % 7 is when a name is a folder
                        mkdir(app.SaveFolderEditField.Value)
                    end

%                     If the preview-while-acquiring check box is checked,
%                     then the preview is started before the actual
%                     acquisition
                    if app.PreviewWhileAcquiringCheckBox.Value
                        PreviewInitialize(app)
                    end
                    
                    % This is a timer object for the actual acquisition. The busymode
                    % queue makes sure that no execution is dropped.
                    % The tasks to be executed are set to 2.
                    % This timer has only two tasks: the first is to start the acquisition
                    % The second is to stop the acquisition and plot
                    app.acqTimerStatus = 0;
                    app.AcquisitionTimer = timer('BusyMode','queue','ExecutionMode','fixedSpacing','Period',app.acqDuration,'TasksToExecute',2,'StartDelay',0.001,'TimerFcn',@(~,~) AcquisitionTimerFcn(app));
                    start(app.AcquisitionTimer)
                    app.acqStatusTxt.Value = 'Acquiring...';
                    app.acqLamp.Color = [1 0 0];
                    app.PupilStartAcquisitionButtonPushed.Enable = 0;
                    app.acqStatus = 1;
            end
        end

        % Button pushed function: generatestimuliFlashBtn
        function generatestimuliFlashBtnPushed(app, event)
%             This is to generate a certain number of stimuli
        minL = app.FlashFastMinL.Value;
        maxL = app.FlashFastMaxL.Value;
        number = app.FlashFastTotN.Value;
        if app.logspacedFlashCk.Value
            if app.includezeroFlashCk.Value
%                 If zero is included, a 0 is added at the beginning of the
%                 stimulus vector.
                StimVect = [0, logspace(log10(minL),log10(maxL),number-1)];
%                 minL is now set to zero, because this is the value that
%                 is going to be used as baseline in the table.
                minL = 0;
            else
                StimVect = logspace(log10(minL),log10(maxL),number);
            end
        else
            StimVect = linspace(minL,maxL,number);
        end
        app.FlashFastTable.Data = horzcat((1:number)',StimVect',repmat(minL,number,1));
        end

        % Value changed function: FlashFastMinL
        function FlashFastMinLValueChanged(app, event)
%             If the logarithmically-spaced check box is checked, the
%             minimum luminance must be more than 0.
            if app.logspacedFlashCk.Value && app.FlashFastMinL.Value==0
                uialert(app.EscherMasterFig,'For logarithmically-spaced stimuli, zero is not a valid input!','Bad input')
                app.FlashFastMinL.Value = event.PreviousValue;
            end
        end

        % Value changed function: CheckFastMinC
        function CheckFastMinCValueChanged(app, event)
%             If the logarithmically-spaced check box is checked, the
%             minimum luminance must be more than 0.
            if app.logspacedCheckCk.Value && app.CheckFastMinC.Value==0
                uialert(app.EscherMasterFig,'For logarithmically-spaced stimuli, zero is not a valid input!','Bad input')
                app.CheckFastMinC.Value = event.PreviousValue;
            end
        end

        % Value changed function: logspacedFlashCk
        function logspacedFlashCkValueChanged(app, event)
%             If the logarithmically-spaced check box is checked, the
%             "include zero" check box must be enabled to allow zero to be
%             inserted as a value in the list.
            if app.logspacedFlashCk.Value
                app.includezeroFlashCk.Enable = 1;
            else
                app.includezeroFlashCk.Enable = 0;
            end
        end

        % Value changed function: logspacedCheckCk
        function logspacedCheckCkValueChanged(app, event)
%             If the logarithmically-spaced check box is checked, the
%             "include zero" check box must be enabled to allow zero to be
%             inserted as a value in the list.
            if app.logspacedCheckCk.Value
                app.includezeroCheckCk.Enable = 1;
            else
                app.includezeroCheckCk.Enable = 0;
            end
        end

        % Button pushed function: generatestimuliCheckBtn
        function generatestimuliCheckBtnButtonPushed(app, event)
%             This is to generate a certain number of stimuli
        minC = app.CheckFastMinC.Value;
        maxC = app.CheckFastMaxC.Value;
        number = app.CheckFastTotN.Value;
        if app.logspacedCheckCk.Value
            if app.includezeroCheckCk.Value
%                 If zero is included, a 0 is added at the beginning of the
%                 stimulus vector.
                StimVect = [0, logspace(log10(minC),log10(maxC),number-1)];
            else
                StimVect = logspace(log10(minC),log10(maxC),number);
            end
        else
            StimVect = linspace(minC,maxC,number);
        end
        app.CheckFastTable.Data = horzcat((1:number)',StimVect',repmat(app.CheckFastMeanL.Value,number,1));
        end

        % Value changed function: CheckFastTotN
        function CheckFastTotNValueChanged(app, event)
            StimTotN = app.CheckFastTotN.Value;
            TableSize = size(app.CheckFastTable.Data,1);
            
            if StimTotN > TableSize
%             A number of rows are added to the table.
                if app.logspacedCheckCk.Value && app.includezeroCheckCk.Value
                    minC = 0;
                else
                    minC = app.CheckFastMinC.Value;
                end
%                 The extra rows are filled with a blank stimulus.
                app.CheckFastTable.Data = vertcat(app.CheckFastTable.Data,...
                    repmat([minC,app.CheckFastMeanL.Value],StimTotN-TableSize,2));
            else
%                 The table is shortened.
                app.CheckFastTable.Data = app.CheckFastTable.Data(1:StimTotN,:);
            end
        end

        % Button pushed function: generatestimuliOrientBtn
        function generatestimuliOrientBtnButtonPushed(app, event)
%             This is to generate a certain number of stimuli
        minA = app.OrientFastMinA.Value;
        maxA = app.OrientFastMaxA.Value;
        number = app.OrientFastTotN.Value;
        StimVect = linspace(minA,maxA,number);
        app.OrientFastTable.Data = horzcat((1:number)',StimVect');
        end

        % Button pushed function: savetableButton
        function savetableButtonPushed(app, event)
%             Converts the content of the table (matrix) to a table.
            StimTable = array2table(app.FlashFastTable.Data);
%             This is for filtering the file type that can be saved.
            filter = {'*.txt;*.dat;*.csv','Delimited text (*.txt,*.dat,*.csv)';...
                '*.*',  'All Files (*.*)'};
%             Opens a dialog window for file selection.
            [file,path] = uiputfile(filter,'Select file to write ','FlashStimuliTable.csv');
            if file == 0
                return
            end
%             The file is saved as a table.
            writetable(StimTable,[file,path])
        end

        % Button pushed function: CheckFastSaveTable
        function CheckFastSaveTableButtonPushed(app, event)
%             Converts the content of the table (matrix) to a table.
            StimTable = array2table(app.CheckFastTable.Data);
%             This is for filtering the file type that can be saved.
            filter = {'*.txt;*.dat;*.csv','Delimited text (*.txt,*.dat,*.csv)';...
                '*.*',  'All Files (*.*)'};
%             Opens a dialog window for file selection.
            [file,path] = uiputfile(filter,'Select file to write ','CheckStimuliTable.csv');
            if file == 0
                return
            end
%             The file is saved as a table.
            writetable(StimTable,[file,path])
        end

        % Button pushed function: OrientFastSaveTable
        function OrientFastSaveTableButtonPushed(app, event)
%             Converts the content of the table (matrix) to a table.
            StimTable = array2table(app.OrientFastTable.Data);
%             This is for filtering the file type that can be saved.
            filter = {'*.txt;*.dat;*.csv','Delimited text (*.txt,*.dat,*.csv)';...
                '*.*',  'All Files (*.*)'};
%             Opens a dialog window for file selection.
            [file,path] = uiputfile(filter,'Select file to write ','OrientStimuliTable.csv');
            if file == 0
                return
            end
%             The file is saved as a table.
            writetable(StimTable,[file,path])
        end

        % Button pushed function: OrientFastLoadTable
        function OrientFastLoadTableButtonPushed(app, event)
            %             Default file types to be read.
            filter = {'*.txt;*.dat;*.csv','Delimited text (*.txt,*.dat,*.csv)';...
                '*.*',  'All Files (*.*)'};
            [file,path] = uigetfile(filter, 'Select file to open','OrientStimuliTable.csv');
            if file==0
                return
            end
            StimTable = readtable([path, file]);
            app.OrientFastTable.Data = StimTable;
        end

        % Button pushed function: RepeatStimBtn
        function RepeatStimBtnButtonPushed(app, event)
            currIdx = app.ExpListCurrentIdx;
            if app.ExpListCurrentIdx > 1
                app.ExperimentTable.Data = vertcat(app.ExperimentTable.Data(1:currIdx-1,:),...
                    app.ExperimentTable.Data(currIdx-1,:), app.ExperimentTable.Data(currIdx:end,:));
                app.ExperimentTable.Data(currIdx:end,1) = ...
                    num2cell(currIdx:app.ExperimentTable.Data{end,1}+1);
            else
                %                 If the index of current experiment is 1 or 0, there is
                %                 nothing to repeat!
                uialert(app.EscherMasterFig,'There is nothing to repeat!','Error')
            end
        end

        % Button pushed function: GetMasterIPandMACBtn
        function GetMasterIPandMACBtnPushed(app, event)
            if ispc
                %                 This is the solution for a Windows-running computer
                [dosStatus,dosReply] = dos('ipconfig /all');
                if dosStatus == 0
                    %                     If DosStatus is 0, the command was executed
                    %                     correctly.
                    
                    %                     First, let's divide the message returned by the DOS
                    %                     command into blocks, that are separated by two
                    %                     consecutive line feeds (\n\n) and NOT followed by a
                    %                     whitespace (\s). I used regex inside strsplit:
                    blocks=strsplit(dosReply,'\n\n(?!\s)','DelimiterType','RegularExpression');
                    %                     Now, usually the first block is for general
                    %                     information, but I prefer not to skip it.
                    %                     We run a search in all blocks for an IP:
                    IpMatch=regexp(blocks,'(?<=IP.*)(?:(?:25[0-5]|2[0-4][0-9]|[01]?[0-9][0-9]?)\.){3}(?:25[0-5]|2[0-4][0-9]|[01]?[0-9][0-9]?)','match','dotexceptnewline');
                    %                     Now we look for the MAC address (physical address).
                    %                     The default format should be XX-XX-XX-XX-XX-XX. It's
                    %                     composed by 12 total hexadecimal digits (= 6 bytes =
                    %                     48 bits).
                    MacMatch=regexp(blocks,'(?<![:-])[A-Fa-f0-9]{2}[:-][A-Fa-f0-9]{2}[:-][A-Fa-f0-9]{2}[:-][A-Fa-f0-9]{2}[:-][A-Fa-f0-9]{2}[:-][A-Fa-f0-9]{2}(?![:-])','match');
                    %                 Find which cells are non-empty in the IpMatch.
               	    LogicIpArray = ~cellfun('isempty',IpMatch);
                    if sum(LogicIpArray)==1
                        %                     Finally, we get the IP!
                        myIp = IpMatch{LogicIpArray}{:};
                        myMac = MacMatch{LogicIpArray}{:};
                        %                         Let's remove the ":" or "-" separator from the
                        %                         MAC address, to make it more general.
                        myMac = regexprep(myMac,'[:-]','');
                    elseif sum(LogicIpArray)>1
                        IpCellArray = [IpMatch{LogicIpArray}];
                        MacCellArray = [MacMatch{LogicIpArray}];
                        MacCellArray = regexprep(MacCellArray,'[:-]','');
                        [indx,tf] = listdlg('PromptString','More than one IP was found. Please choose one:',...
                            'SelectionMode','single','ListString',IpCellArray);
                        if tf == 0
                            return
                        end
                        %                     Finally, we get the IP!
                        myIp = IpCellArray{indx};
                        myMac = MacCellArray{indx};
                    else
                        uialert(app.EscherMasterFig,'IP search did not get any result.',...
                            'Error')
                    end
                else
                    uialert(app.EscherMasterFig,'DOS command was not correctly executed.','Error')
                end
                app.MasterIp.Value = myIp;
                app.MasterMac.Value = myMac;
            else
                uialert(app.EscherMasterFig,'Currently only for Windows','Warning')
            end
        end

        % Button pushed function: StartUdpBtn
        function StartUdpBtnButtonPushed(app, event)
            if ~app.UdpConnectionFlag
                if isempty(app.MasterMac.Value)||strcmp(app.SlaveIp.Value,'0.0.0.0')||strcmp(app.MasterIp.Value,'0.0.0.0')
                    uialert(app.EscherMasterFig,'Invalid Addresses','Error')
                    return
                end
                if app.ScreenOpenFlag
%                 If the screen is open the app will ask for confirmation to start working as master.
                    selection = uiconfirm(app.EscherMasterFig, ['The app will start now working as master.' ...
                        'This will close any open screen. Do you confirm?'], 'Warning');
                    if strcmp(selection,'OK')
                        OpenScreenBtn_Callback(app, [])
                    else
                        return
                    end
                end
                                
%                 Change the status text, while waiting for connection
%                 Also, the button for starting the connection is being
%                 disabled. When the app receives an answer from the UDP
%                 connection OR when the timer times out.
                EnableGoBtn(app,0)
%                 All the connection parameters cannot be changed while the
%                 connection is ongoing.
                set([app.MasterIp,app.MasterMac,app.MasterPort,app.SlaveIp,app.SlavePort],'Editable',0);
                app.ConnectionStatus.Value = 'Connecting';
                
%                 This is the UDP object, used to make the master (this app)
%                 communicate with the slave (the app actually performing the
%                 stimulation).
                try
                    app.UdpObj.RemoteHost = app.SlaveIp.Value;
                    app.UdpObj.RemotePort = app.SlavePort.Value;
                    app.UdpOnj.LocalPort = app.MasterPort.Value;
%                     Open the UDP object.
                    fopen(app.UdpObj);
                catch
%                     If for some reason, the UDP connection cannot be
%                     opened, we must close all connections.
%                     Erase all UDP connections and initialize a UDP object.
                    CloseAllUdp(app)
                    app.UdpObj = udp(app.SlaveIp.Value, 'RemotePort',...
                        app.SlavePort.Value, 'LocalPort', app.MasterPort.Value);
                    fopen(app.UdpObj);
                end
%                 Callback function for when a datagram is received. If a
%                 confirmation message is read, the timer is stopped.
                app.UdpObj.DatagramReceivedFcn = @(obj,~) UdpDataReceivedCallback(app,obj);
                
%                 And start reading.
                readasync(app.UdpObj)
                app.UdpObj.ReadAsyncMode = 'continuous';
                
%                 Send a message to start the connection. It contains the
%                 IP, MAC and port of the master.
                msg=strjoin([{'start'},{app.MasterIp.Value},{app.MasterMac.Value},...
                    {num2str(app.MasterPort.Value)}],',');
                SendUdpMsg(app,msg,app.TimeoutTime.Value)
            else
%                 If the connection was ongoing, the button is used to
%                 cancel it.
                stopasync(app.UdpObj)
                fclose(app.UdpObj);
%                 The button is reenabled, if it was not.
                EnableGoBtn(app,1)
%                 All the connection parameters cannot be changed while the
%                 connection is ongoing. So now they can be changed again
                set([app.MasterIp,app.MasterMac,app.MasterPort,app.SlaveIp,app.SlavePort],'Editable',1);%                 If for some reason the timer for UDP timeout is running,
%                 it is stopped.
                if isvalid(app.UdpTimeoutTimer)
                    stop(app.UdpTimeoutTimer)
                end
%                 The connection is undone, so the buttons and other stuff must be changed
%                 accordingly.
%                 Revert the button back to its status. Also the flag value
%                 is set back to 0.
                app.StartUdpBtn.Text = 'Start Connection';
                app.ConnectionStatus.Value = 'Disconnected.';
                app.ResendScreenParams.Enable = 1;
                app.UDPConnectionLamp.Color = [1 0 0];
                app.UdpConnectionFlag = 0;
            end
        end

        % Button pushed function: ResendScreenParams
        function ResendScreenParamsButtonPushed(app, event)
            msg = ScreenParameterMsg(app);
            SendUdpMsg(app,msg,app.TimeoutTime.Value)
        end

        % Button pushed function: DeBusyButton
        function DeBusyButtonPushed(app, event)
            EnableGoBtn(app,1)
        end

        % Value changed function: ScreenDistance
        function ScreenDistanceValueChanged(app, event)
            SetScreenSizeAngle(app)
        end

        % Button pushed function: SaveScreenFileButton
        function SaveScreenFileButtonPushed(app, event)
            [file,path] = uiputfile({'*.dat;*.csv;*.txt',...
                'Text files (*.dat,*.csv,*.txt)';...
                '*.*',  'All Files (*.*)'}, 'Save a File');
            if file==0
                return
            end
            
            T = table;
            
            T.name{1} = app.ScreenNameTxt.Value;
            T.width(1) = app.ScreenWidth.Value;
            T.height(1) = app.ScreenHeight.Value;
            T.formula{1} = app.ScreenFuncTxt.Value;
            T.notes{1} = app.ScreenNotesTxt.Value;
            
            writetable(T,[path, file])
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EscherMasterFig = uifigure('Visible', 'off');
            app.EscherMasterFig.Name = 'EscherMaster';
            app.EscherMasterFig.CloseRequestFcn = createCallbackFcn(app, @EscherMasterFigCloseRequest, true);
            app.TabGroup = uitabgroup(app.EscherMasterFig);
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.uipanel6 = uipanel(app.SettingsTab);
            app.uipanel6.Title = 'Setting Screen and Synchronisation';
            app.OpenScreenBtn = uibutton(app.uipanel6, 'push');
            app.OpenScreenBtn.ButtonPushedFcn = createCallbackFcn(app, @OpenScreenBtn_Callback, true);
            app.OpenScreenBtn.Tooltip = {'Open screen when in dual monitor mode (default)'};
            app.OpenScreenBtn.Text = 'Open Screen';
            app.RefreshStBy = uibutton(app.uipanel6, 'push');
            app.RefreshStBy.ButtonPushedFcn = createCallbackFcn(app, @RefreshStByButtonPushed, true);
            app.RefreshStBy.Tooltip = {'Fill screen with stand by luminance'};
            app.RefreshStBy.Text = 'Standby';
            app.OneScreenMode_ck = uicheckbox(app.uipanel6);
            app.OneScreenMode_ck.Text = 'One Screen Mode';
            app.oculusCk = uicheckbox(app.uipanel6);
            app.oculusCk.Text = 'VR goggles';
            app.SetstandbyluminanceEditFieldLabel = uilabel(app.uipanel6);
            app.SetstandbyluminanceEditFieldLabel.Text = 'Set standby luminance';
            app.StandbyL = uieditfield(app.uipanel6, 'numeric');
            app.LabelLumi_1 = uilabel(app.uipanel6);
            app.LabelLumi_1.Text = 'cd/m²';
            app.uipanel4 = uipanel(app.SettingsTab);
            app.uipanel4.Title = 'Monitor Size and Geometry';
            app.ScreendistancecmLabel = uilabel(app.uipanel4);
            app.ScreendistancecmLabel.Tooltip = {'Enter the three parameters definig the screen angular size. '};
            app.ScreendistancecmLabel.Text = 'Screen distance (cm):';
            app.ScreenDistance = uieditfield(app.uipanel4, 'numeric');
            app.ScreenDistance.Limits = [0 120];
            app.ScreenDistance.RoundFractionalValues = 'on';
            app.ScreenDistance.ValueDisplayFormat = '%4.3g';
            app.ScreenDistance.ValueChangedFcn = createCallbackFcn(app, @ScreenDistanceValueChanged, true);
            app.ScreenDistance.Value = 30;
            app.WidthpixelsEditFieldLabel = uilabel(app.uipanel4);
            app.WidthpixelsEditFieldLabel.Text = 'Width (pixels):';
            app.WidthPix = uieditfield(app.uipanel4, 'numeric');
            app.WidthPix.Limits = [0 Inf];
            app.WidthPix.RoundFractionalValues = 'on';
            app.WidthPix.Editable = 'off';
            app.WidthPix.Value = 1240;
            app.HeightpixelsEditFieldLabel = uilabel(app.uipanel4);
            app.HeightpixelsEditFieldLabel.Text = 'Height (pixels):';
            app.HeightPix = uieditfield(app.uipanel4, 'numeric');
            app.HeightPix.Limits = [0 Inf];
            app.HeightPix.RoundFractionalValues = 'on';
            app.HeightPix.Editable = 'off';
            app.HeightPix.Value = 800;
            app.WidthcmLabel = uilabel(app.uipanel4);
            app.WidthcmLabel.Text = 'Width (cm)';
            app.ScreenWidth = uieditfield(app.uipanel4, 'numeric');
            app.ScreenWidth.Limits = [0 Inf];
            app.ScreenWidth.ValueChangedFcn = createCallbackFcn(app, @ScreenWidthValueChanged, true);
            app.ScreenWidth.Value = 34;
            app.HeightcmEditFieldLabel = uilabel(app.uipanel4);
            app.HeightcmEditFieldLabel.Text = 'Height (cm)';
            app.ScreenHeight = uieditfield(app.uipanel4, 'numeric');
            app.ScreenHeight.Limits = [0 Inf];
            app.ScreenHeight.ValueChangedFcn = createCallbackFcn(app, @ScreenHeightValueChanged, true);
            app.ScreenHeight.Value = 27;
            app.AngularsizeofscreenEditFieldLabel = uilabel(app.uipanel4);
            app.AngularsizeofscreenEditFieldLabel.Text = 'Angular size of screen';
            app.ScreenSizeAngle = uieditfield(app.uipanel4, 'text');
            app.SynchronizationPanel = uipanel(app.SettingsTab);
            app.SynchronizationPanel.Title = 'Synchronization';
            app.ard_ck = uicheckbox(app.SynchronizationPanel);
            app.ard_ck.Tooltip = {'Display the Hermes synchronisation cue'};
            app.ard_ck.Text = 'Hermes Synch';
            app.ard_ck.Value = true;
            app.HeightpxEditFieldLabel = uilabel(app.SynchronizationPanel);
            app.HeightpxEditFieldLabel.Text = 'Height (px)';
            app.hhTxt = uieditfield(app.SynchronizationPanel, 'numeric');
            app.hhTxt.Limits = [0 Inf];
            app.hhTxt.Value = 100;
            app.CueWidthpxEditFieldLabel = uilabel(app.SynchronizationPanel);
            app.CueWidthpxEditFieldLabel.Text = 'Cue Width (px)';
            app.wwTxt = uieditfield(app.SynchronizationPanel, 'numeric');
            app.wwTxt.Limits = [0 Inf];
            app.wwTxt.Value = 100;
            app.calblack_btn = uibutton(app.SynchronizationPanel, 'push');
            app.calblack_btn.ButtonPushedFcn = createCallbackFcn(app, @calblack_btn_Callback, true);
            app.calblack_btn.Text = 'C-Black';
            app.calwhite_btn = uibutton(app.SynchronizationPanel, 'push');
            app.calwhite_btn.ButtonPushedFcn = createCallbackFcn(app, @calwhite_btn_Callback, true);
            app.calwhite_btn.Text = 'C-White';
            app.CuepositionPanel = uipanel(app.SynchronizationPanel);
            app.CuepositionPanel.Title = 'Cue position';
            app.ButtonGroup_2 = uibuttongroup(app.CuepositionPanel);
            app.CueTopRB = uiradiobutton(app.ButtonGroup_2);
            app.CueTopRB.Text = 'Top';
            app.CueBotRB = uiradiobutton(app.ButtonGroup_2);
            app.CueBotRB.Text = 'Bottom';
            app.CueBotRB.Value = true;
            app.ButtonGroup_3 = uibuttongroup(app.CuepositionPanel);
            app.CueLeftRB = uiradiobutton(app.ButtonGroup_3);
            app.CueLeftRB.Text = 'Left';
            app.CueRightRB = uiradiobutton(app.ButtonGroup_3);
            app.CueRightRB.Text = 'Right';
            app.CueRightRB.Value = true;
            app.DurationofcuemsEditFieldLabel = uilabel(app.SynchronizationPanel);
            app.DurationofcuemsEditFieldLabel.Text = 'Duration of cue (ms):';
            app.optDtrTimeTxt = uieditfield(app.SynchronizationPanel, 'numeric');
            app.optDtrTimeTxt.Limits = [0 Inf];
            app.optDtrTimeTxt.Value = 100;
            app.UDPConnectionPanel = uipanel(app.SettingsTab);
            app.UDPConnectionPanel.Title = 'UDP Connection';
            app.StartUdpBtn = uibutton(app.UDPConnectionPanel, 'push');
            app.StartUdpBtn.ButtonPushedFcn = createCallbackFcn(app, @StartUdpBtnButtonPushed, true);
            app.StartUdpBtn.Tooltip = {'Start UDP connection to the slave Escher app. The slave must be in listening mode.'};
            app.StartUdpBtn.Text = 'Start Connection';
            app.SlaveIPLabel = uilabel(app.UDPConnectionPanel);
            app.SlaveIPLabel.Text = 'Slave IP:';
            app.SlaveIp = uieditfield(app.UDPConnectionPanel, 'text');
            app.SlaveIp.Tooltip = {'Set here the IP address of the slave app.'};
            app.SlaveIp.Value = '0.0.0.0';
            app.PortEditFieldLabel = uilabel(app.UDPConnectionPanel);
            app.PortEditFieldLabel.Text = 'Port:';
            app.SlavePort = uieditfield(app.UDPConnectionPanel, 'numeric');
            app.SlavePort.Limits = [1023 65535];
            app.SlavePort.RoundFractionalValues = 'on';
            app.SlavePort.ValueDisplayFormat = '%8.5g';
            app.SlavePort.Tooltip = {'Port for the UDP connection on the slave side. It must be the same port on which the slave is listening. Default: 8866.'};
            app.SlavePort.Value = 8866;
            app.MasterIPLabel = uilabel(app.UDPConnectionPanel);
            app.MasterIPLabel.Text = 'Master IP:';
            app.MasterIp = uieditfield(app.UDPConnectionPanel, 'text');
            app.MasterIp.Tooltip = {'Set here the IP address of the master (this app).'};
            app.MasterIp.Value = '0.0.0.0';
            app.PortEditFieldLabel_2 = uilabel(app.UDPConnectionPanel);
            app.PortEditFieldLabel_2.Text = 'Port:';
            app.MasterPort = uieditfield(app.UDPConnectionPanel, 'numeric');
            app.MasterPort.Limits = [1023 65535];
            app.MasterPort.RoundFractionalValues = 'on';
            app.MasterPort.ValueDisplayFormat = '%8.5g';
            app.MasterPort.Tooltip = {'Port for the UDP connection on the master side. Default: 8844.'};
            app.MasterPort.Value = 8844;
            app.GetMasterIPandMACBtn = uibutton(app.UDPConnectionPanel, 'push');
            app.GetMasterIPandMACBtn.ButtonPushedFcn = createCallbackFcn(app, @GetMasterIPandMACBtnPushed, true);
            app.GetMasterIPandMACBtn.Tooltip = {'Get the IP and MAC of this computer (master).'};
            app.GetMasterIPandMACBtn.Text = 'Get Master IP and MAC';
            app.StatusEditFieldLabel_2 = uilabel(app.UDPConnectionPanel);
            app.StatusEditFieldLabel_2.Text = 'Status:';
            app.ConnectionStatus = uieditfield(app.UDPConnectionPanel, 'text');
            app.ConnectionStatus.Editable = 'off';
            app.ConnectionStatus.Value = 'Disconnected.';
            app.MACLabel_3 = uilabel(app.UDPConnectionPanel);
            app.MACLabel_3.Text = 'MAC:';
            app.MasterMac = uieditfield(app.UDPConnectionPanel, 'text');
            app.MasterMac.Tooltip = {'The physical address of the master (this app). It is used by the app as identifier.'};
            app.MasterMac.Value = '000000000000';
            app.MACLabel_2 = uilabel(app.UDPConnectionPanel);
            app.MACLabel_2.Text = 'MAC:';
            app.SlaveMac = uieditfield(app.UDPConnectionPanel, 'text');
            app.SlaveMac.Editable = 'off';
            app.SlaveMac.Tooltip = {'The physical address of the slave app. It is going to be given by the slave itself.'};
            app.SlaveMac.Value = '000000000000';
            app.TimeouttimesEditFieldLabel = uilabel(app.UDPConnectionPanel);
            app.TimeouttimesEditFieldLabel.Text = 'Timeout time (s)';
            app.TimeoutTime = uieditfield(app.UDPConnectionPanel, 'numeric');
            app.TimeoutTime.Limits = [0 Inf];
            app.TimeoutTime.Tooltip = {'The time after which'; ' if the master does not receive any answer from the slave'; ' the UDP connection will be cut.'};
            app.TimeoutTime.Value = 20;
            app.ResendScreenParams = uibutton(app.UDPConnectionPanel, 'push');
            app.ResendScreenParams.ButtonPushedFcn = createCallbackFcn(app, @ResendScreenParamsButtonPushed, true);
            app.ResendScreenParams.Enable = 'off';
            app.ResendScreenParams.Tooltip = {'Send again the screen parameters of the Settings Tab to the slave app.'};
            app.ResendScreenParams.Text = {'Resend Screen'; 'Parameters'};
            app.LoadScreenInformationPanel = uipanel(app.SettingsTab);
            app.LoadScreenInformationPanel.Title = 'Load Screen Information';
            app.LoadScreenFileButton = uibutton(app.LoadScreenInformationPanel, 'push');
            app.LoadScreenFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadScreenFileButtonPushed, true);
            app.LoadScreenFileButton.Text = 'Load Screen File';
            app.ScreennameEditFieldLabel = uilabel(app.LoadScreenInformationPanel);
            app.ScreennameEditFieldLabel.Text = 'Screen name';
            app.ScreenNameTxt = uieditfield(app.LoadScreenInformationPanel, 'text');
            app.ScreenNameTxt.Value = 'asus large B0';
            app.ScreenTransferFormulaLabel = uilabel(app.LoadScreenInformationPanel);
            app.ScreenTransferFormulaLabel.Text = {'Screen '; 'Transfer Formula:'};
            app.ScreenFuncTxt = uieditfield(app.LoadScreenInformationPanel, 'text');
            app.ScreenFuncTxt.Value = '@(x)-0.01+11.25*x.^1.9';
            app.NotesLabel = uilabel(app.LoadScreenInformationPanel);
            app.NotesLabel.Text = 'Notes:';
            app.ScreenNotesTxt = uieditfield(app.LoadScreenInformationPanel, 'text');
            app.ScreenNotesTxt.Value = 'standard ; K=100; B=0';
            app.SaveScreenFileButton = uibutton(app.LoadScreenInformationPanel, 'push');
            app.SaveScreenFileButton.ButtonPushedFcn = createCallbackFcn(app, @SaveScreenFileButtonPushed, true);
            app.SaveScreenFileButton.Text = 'Save Screen File';
            app.luminanceLabel = uilabel(app.LoadScreenInformationPanel);
            app.luminanceLabel.Text = 'luminance=';
            app.CalModeRBGroup = uibuttongroup(app.LoadScreenInformationPanel);
            app.CalModeRBGroup.SelectionChangedFcn = createCallbackFcn(app, @CalModeRBGroupSelectionChanged, true);
            app.CalModeRBGroup.Title = 'Mode:';
            app.UncalibratedButton = uiradiobutton(app.CalModeRBGroup);
            app.UncalibratedButton.Text = 'Uncalibrated';
            app.CalibratedButton = uiradiobutton(app.CalModeRBGroup);
            app.CalibratedButton.Text = 'Calibrated';
            app.CalibratedButton.Value = true;
            app.StimulationTab = uitab(app.TabGroup);
            app.StimulationTab.Title = 'Stimulation';
            app.FlashPanel = uipanel(app.StimulationTab);
            app.FlashPanel.Title = 'Full Screen Flash';
            app.BaselineluminanceLabel = uilabel(app.FlashPanel);
            app.BaselineluminanceLabel.Tooltip = {'Luminance of the full screen away from the flash.'};
            app.BaselineluminanceLabel.Text = 'Baseline luminance:';
            app.FSbaselineL = uieditfield(app.FlashPanel, 'numeric');
            app.FSbaselineL.Limits = [0 Inf];
            app.FSbaselineL.ValueDisplayFormat = '%5.3g';
            app.FSbaselineL.ValueChangedFcn = createCallbackFcn(app, @FSbaselineLValueChanged, true);
            app.FSbaselineL.Tooltip = {'Luminance of the background'};
            app.FlashluminanceLabel = uilabel(app.FlashPanel);
            app.FlashluminanceLabel.Text = {'Flash luminance:'; ''};
            app.FSflashL = uieditfield(app.FlashPanel, 'numeric');
            app.FSflashL.Limits = [0 Inf];
            app.FSflashL.ValueChangedFcn = createCallbackFcn(app, @FSflashLValueChanged, true);
            app.FSflashL.Tooltip = {'Luminance of the flash stimulus'};
            app.FSflashL.Value = 0.8;
            app.RepetitionsLabel_2 = uilabel(app.FlashPanel);
            app.RepetitionsLabel_2.Text = 'Repetitions:';
            app.FlashRepetitions = uieditfield(app.FlashPanel, 'numeric');
            app.FlashRepetitions.Limits = [0 Inf];
            app.FlashRepetitions.Value = 20;
            app.PeriodsEditFieldLabel = uilabel(app.FlashPanel);
            app.PeriodsEditFieldLabel.Text = 'Period (s)';
            app.FlashPeriod = uieditfield(app.FlashPanel, 'numeric');
            app.FlashPeriod.Limits = [0 Inf];
            app.FlashPeriod.Value = 4;
            app.LabelLumi_2 = uilabel(app.FlashPanel);
            app.LabelLumi_2.Text = 'cd/m²';
            app.LabelLumi_3 = uilabel(app.FlashPanel);
            app.LabelLumi_3.Text = 'cd/m²';
            app.FlashdurationsLabel = uilabel(app.FlashPanel);
            app.FlashdurationsLabel.Text = 'Flash duration (s)';
            app.FlashDuration = uieditfield(app.FlashPanel, 'numeric');
            app.FlashDuration.Limits = [0 Inf];
            app.FlashDuration.Value = 0.1;
            app.FSbaselineLEsch = uieditfield(app.FlashPanel, 'numeric');
            app.FSbaselineLEsch.Limits = [0 Inf];
            app.FSbaselineLEsch.ValueDisplayFormat = '%5.3g';
            app.FSbaselineLEsch.Editable = 'off';
            app.FSbaselineLEsch.Tooltip = {'Luminance of the background'};
            app.LabelLumiEsch_2 = uilabel(app.FlashPanel);
            app.LabelLumiEsch_2.Text = '(0-1)';
            app.FSflashLEsch = uieditfield(app.FlashPanel, 'numeric');
            app.FSflashLEsch.Limits = [0 Inf];
            app.FSflashLEsch.Editable = 'off';
            app.FSflashLEsch.Tooltip = {'Luminance of the flash stimulus'};
            app.FSflashLEsch.Value = 0.8;
            app.LabelLumiEsch_3 = uilabel(app.FlashPanel);
            app.LabelLumiEsch_3.Text = '(0-1)';
            app.FastFlashStimulusSelectionPanel = uipanel(app.FlashPanel);
            app.FastFlashStimulusSelectionPanel.Title = 'Fast Flash Stimulus Selection';
            app.FlashFastTable = uitable(app.FastFlashStimulusSelectionPanel);
            app.FlashFastTable.ColumnName = {'Stim. n.'; 'Flash L'; 'Base L'};
            app.FlashFastTable.RowName = {};
            app.FlashFastTable.ColumnEditable = [false true true];
            app.noftotstimLabel = uilabel(app.FastFlashStimulusSelectionPanel);
            app.noftotstimLabel.Text = 'n. of tot stim';
            app.FlashFastTotN = uieditfield(app.FastFlashStimulusSelectionPanel, 'numeric');
            app.FlashFastTotN.Limits = [1 Inf];
            app.FlashFastTotN.RoundFractionalValues = 'on';
            app.FlashFastTotN.ValueChangedFcn = createCallbackFcn(app, @FlashFastTotNValueChanged, true);
            app.FlashFastTotN.Value = 9;
            app.MinLEditFieldLabel = uilabel(app.FastFlashStimulusSelectionPanel);
            app.MinLEditFieldLabel.Text = 'Min L';
            app.FlashFastMinL = uieditfield(app.FastFlashStimulusSelectionPanel, 'numeric');
            app.FlashFastMinL.Limits = [0 Inf];
            app.FlashFastMinL.ValueChangedFcn = createCallbackFcn(app, @FlashFastMinLValueChanged, true);
            app.FlashFastMinL.Value = 0.1;
            app.MaxLEditFieldLabel = uilabel(app.FastFlashStimulusSelectionPanel);
            app.MaxLEditFieldLabel.Text = 'Max L';
            app.FlashFastMaxL = uieditfield(app.FastFlashStimulusSelectionPanel, 'numeric');
            app.FlashFastMaxL.Limits = [0 Inf];
            app.FlashFastMaxL.Value = 0.6;
            app.logspacedFlashCk = uicheckbox(app.FastFlashStimulusSelectionPanel);
            app.logspacedFlashCk.ValueChangedFcn = createCallbackFcn(app, @logspacedFlashCkValueChanged, true);
            app.logspacedFlashCk.Text = 'log spaced';
            app.logspacedFlashCk.Value = true;
            app.includezeroFlashCk = uicheckbox(app.FastFlashStimulusSelectionPanel);
            app.includezeroFlashCk.Text = 'include zero';
            app.includezeroFlashCk.Value = true;
            app.generatestimuliFlashBtn = uibutton(app.FastFlashStimulusSelectionPanel, 'push');
            app.generatestimuliFlashBtn.ButtonPushedFcn = createCallbackFcn(app, @generatestimuliFlashBtnPushed, true);
            app.generatestimuliFlashBtn.Text = {'generate stimuli'; 'table'};
            app.savetableButton = uibutton(app.FastFlashStimulusSelectionPanel, 'push');
            app.savetableButton.ButtonPushedFcn = createCallbackFcn(app, @savetableButtonPushed, true);
            app.savetableButton.Text = 'save table';
            app.FlashFastLoadTable = uibutton(app.FastFlashStimulusSelectionPanel, 'push');
            app.FlashFastLoadTable.ButtonPushedFcn = createCallbackFcn(app, @FlashFastLoadTableButtonPushed, true);
            app.FlashFastLoadTable.Text = 'load table';
            app.ChoosestimEditFieldLabel = uilabel(app.FastFlashStimulusSelectionPanel);
            app.ChoosestimEditFieldLabel.Text = 'Choose stim.';
            app.FlashFastN = uieditfield(app.FastFlashStimulusSelectionPanel, 'numeric');
            app.FlashFastN.Limits = [1 Inf];
            app.FlashFastN.RoundFractionalValues = 'on';
            app.FlashFastN.ValueDisplayFormat = '%.0f';
            app.FlashFastN.ValueChangedFcn = createCallbackFcn(app, @FlashFastN_Callback, true);
            app.FlashFastN.Value = 1;
            app.FSFlashGo = uibutton(app.FlashPanel, 'push');
            app.FSFlashGo.ButtonPushedFcn = createCallbackFcn(app, @GoFlash_Callback, true);
            app.FSFlashGo.Text = 'GO!';
            app.uipanel14 = uipanel(app.StimulationTab);
            app.uipanel14.Title = 'General Settings';
            app.InitialdelaysLabel = uilabel(app.uipanel14);
            app.InitialdelaysLabel.Text = 'Initial delay (s):';
            app.initialDelay = uieditfield(app.uipanel14, 'numeric');
            app.initialDelay.Limits = [0 Inf];
            app.initialDelay.Tooltip = {'Initial delay from the start of the sequence to the apparition of the first stimulus. The screen is set to the baseline or mean luminance'};
            app.initialDelay.Value = 1;
            app.ContrastStimulationPanel = uipanel(app.StimulationTab);
            app.ContrastStimulationPanel.Title = 'Contrast Stimulation';
            app.LabelLumi_4 = uilabel(app.ContrastStimulationPanel);
            app.LabelLumi_4.Text = 'cd/m²';
            app.BlackluminanceLabel = uilabel(app.ContrastStimulationPanel);
            app.BlackluminanceLabel.Text = {'Black luminance:'; ''};
            app.CheckLumiB = uieditfield(app.ContrastStimulationPanel, 'numeric');
            app.CheckLumiB.Limits = [0 Inf];
            app.CheckLumiB.ValueChangedFcn = createCallbackFcn(app, @CheckLumiBValueChanged, true);
            app.CheckLumiB.Tooltip = {'Luminance of the flash stimulus'};
            app.LabelLumi_5 = uilabel(app.ContrastStimulationPanel);
            app.LabelLumi_5.Text = 'cd/m²';
            app.WhiteluminanceLabel = uilabel(app.ContrastStimulationPanel);
            app.WhiteluminanceLabel.Text = {'White luminance:'; ''};
            app.CheckLumiW = uieditfield(app.ContrastStimulationPanel, 'numeric');
            app.CheckLumiW.Limits = [0 Inf];
            app.CheckLumiW.ValueChangedFcn = createCallbackFcn(app, @CheckLumiWValueChanged, true);
            app.CheckLumiW.Tooltip = {'Luminance of the flash stimulus'};
            app.CheckLumiW.Value = 0.6;
            app.LabelLumi_6 = uilabel(app.ContrastStimulationPanel);
            app.LabelLumi_6.Text = 'cd/m²';
            app.MeanluminanceLabel = uilabel(app.ContrastStimulationPanel);
            app.MeanluminanceLabel.Tooltip = {'Luminance of the full screen away from the flash.'};
            app.MeanluminanceLabel.Text = 'Mean luminance:';
            app.CheckLumiMean = uieditfield(app.ContrastStimulationPanel, 'numeric');
            app.CheckLumiMean.Limits = [0 Inf];
            app.CheckLumiMean.ValueDisplayFormat = '%5.3g';
            app.CheckLumiMean.ValueChangedFcn = createCallbackFcn(app, @CheckLumiMeanValueChanged, true);
            app.CheckLumiMean.Tooltip = {'Luminance of the background'};
            app.CheckLumiMean.Value = 0.3;
            app.ContrastLabel = uilabel(app.ContrastStimulationPanel);
            app.ContrastLabel.Text = 'Contrast (%)';
            app.CheckContrast = uieditfield(app.ContrastStimulationPanel, 'numeric');
            app.CheckContrast.Limits = [0 100];
            app.CheckContrast.ValueChangedFcn = createCallbackFcn(app, @CheckContrastValueChanged, true);
            app.CheckContrast.Tooltip = {'Luminance of the flash stimulus'};
            app.CheckContrast.Value = 100;
            app.CheckerboardPanel = uipanel(app.ContrastStimulationPanel);
            app.CheckerboardPanel.Title = 'Checkerboard';
            app.CheckGo = uibutton(app.CheckerboardPanel, 'push');
            app.CheckGo.ButtonPushedFcn = createCallbackFcn(app, @GoCheck_Callback, true);
            app.CheckGo.Text = 'GO!';
            app.RepetitionsEditField_2Label = uilabel(app.CheckerboardPanel);
            app.RepetitionsEditField_2Label.Text = 'Repetitions:';
            app.CheckRepetitions = uieditfield(app.CheckerboardPanel, 'numeric');
            app.CheckRepetitions.Value = 20;
            app.PeriodsEditField_2Label = uilabel(app.CheckerboardPanel);
            app.PeriodsEditField_2Label.Text = 'Period (s)';
            app.CheckPeriod = uieditfield(app.CheckerboardPanel, 'numeric');
            app.CheckPeriod.Limits = [0 Inf];
            app.CheckPeriod.Value = 4;
            app.GratingsPanel = uipanel(app.ContrastStimulationPanel);
            app.GratingsPanel.Title = 'Periodic Gratings';
            app.SliderOrientation = uislider(app.GratingsPanel);
            app.SliderOrientation.Limits = [0 360];
            app.SliderOrientation.MajorTicks = [];
            app.SliderOrientation.ValueChangedFcn = createCallbackFcn(app, @SliderOrientation_Callback, true);
            app.SliderOrientation.MinorTicks = [];
            app.GoGrid = uibutton(app.GratingsPanel, 'push');
            app.GoGrid.ButtonPushedFcn = createCallbackFcn(app, @GoGrid_Callback, true);
            app.GoGrid.Text = 'GO!';
            app.DrawMask = uicheckbox(app.GratingsPanel);
            app.DrawMask.Text = '';
            app.ButtonGroup = uibuttongroup(app.GratingsPanel);
            app.SineGrid = uiradiobutton(app.ButtonGroup);
            app.SineGrid.Text = 'Sinusoidal';
            app.SquareGrid = uiradiobutton(app.ButtonGroup);
            app.SquareGrid.Text = 'Square';
            app.SquareGrid.Value = true;
            app.GridorientationdegEditFieldLabel = uilabel(app.GratingsPanel);
            app.GridorientationdegEditFieldLabel.Text = 'Grid orientation (deg):';
            app.GridOrientation = uieditfield(app.GratingsPanel, 'numeric');
            app.GridOrientation.Limits = [0 360];
            app.GridOrientation.ValueChangedFcn = createCallbackFcn(app, @GridOrientationValueChanged, true);
            app.RepetitionsEditFieldLabel = uilabel(app.GratingsPanel);
            app.RepetitionsEditFieldLabel.Text = 'Repetitions';
            app.GridRepetitions = uieditfield(app.GratingsPanel, 'numeric');
            app.GridRepetitions.Limits = [0 Inf];
            app.GridRepetitions.Value = 20;
            app.BaselineGratingPostEditFieldLabel = uilabel(app.GratingsPanel);
            app.BaselineGratingPostEditFieldLabel.Text = 'Baseline, Grating, Post';
            app.PreGridDuration = uieditfield(app.GratingsPanel, 'numeric');
            app.PreGridDuration.Limits = [0 Inf];
            app.PreGridDuration.Value = 0.5;
            app.GridDuration = uieditfield(app.GratingsPanel, 'numeric');
            app.GridDuration.Limits = [0 Inf];
            app.GridDuration.Value = 2;
            app.PostGridDuration = uieditfield(app.GratingsPanel, 'numeric');
            app.PostGridDuration.Limits = [0 Inf];
            app.PostGridDuration.Value = 1.5;
            app.DriftingspeedcyclessecEditFieldLabel = uilabel(app.GratingsPanel);
            app.DriftingspeedcyclessecEditFieldLabel.Text = 'Drifting speed (cycles/sec):';
            app.DriftingSpeed_txt = uieditfield(app.GratingsPanel, 'numeric');
            app.DriftingSpeed_txt.Limits = [0 Inf];
            app.DriftingSpeed_txt.ValueChangedFcn = createCallbackFcn(app, @DriftingSpeed_txt_Callback, true);
            app.DriftingSpeed_txt.Value = 2;
            app.SizegaussianmaskpixEditFieldLabel = uilabel(app.GratingsPanel);
            app.SizegaussianmaskpixEditFieldLabel.Text = 'Size gaussian mask (pix):';
            app.GaussSize_txt = uieditfield(app.GratingsPanel, 'numeric');
            app.GaussSize_txt.Value = 300;
            app.FastOrientationStimulusSelectionPanel = uipanel(app.GratingsPanel);
            app.FastOrientationStimulusSelectionPanel.Title = 'Fast Orientation Stimulus Selection';
            app.generatestimuliOrientBtn = uibutton(app.FastOrientationStimulusSelectionPanel, 'push');
            app.generatestimuliOrientBtn.ButtonPushedFcn = createCallbackFcn(app, @generatestimuliOrientBtnButtonPushed, true);
            app.generatestimuliOrientBtn.Text = {'generate stimuli'; 'table'};
            app.OrientFastSaveTable = uibutton(app.FastOrientationStimulusSelectionPanel, 'push');
            app.OrientFastSaveTable.ButtonPushedFcn = createCallbackFcn(app, @OrientFastSaveTableButtonPushed, true);
            app.OrientFastSaveTable.Text = 'save table';
            app.OrientFastLoadTable = uibutton(app.FastOrientationStimulusSelectionPanel, 'push');
            app.OrientFastLoadTable.ButtonPushedFcn = createCallbackFcn(app, @OrientFastLoadTableButtonPushed, true);
            app.OrientFastLoadTable.Text = 'load table';
            app.noftotstimLabel_3 = uilabel(app.FastOrientationStimulusSelectionPanel);
            app.noftotstimLabel_3.Text = 'n. of tot stim';
            app.OrientFastTotN = uieditfield(app.FastOrientationStimulusSelectionPanel, 'numeric');
            app.OrientFastTotN.Value = 4;
            app.StartAngleEditFieldLabel = uilabel(app.FastOrientationStimulusSelectionPanel);
            app.StartAngleEditFieldLabel.Text = 'Start Angle';
            app.OrientFastMinA = uieditfield(app.FastOrientationStimulusSelectionPanel, 'numeric');
            app.EndAngleEditFieldLabel = uilabel(app.FastOrientationStimulusSelectionPanel);
            app.EndAngleEditFieldLabel.Text = 'End Angle';
            app.OrientFastMaxA = uieditfield(app.FastOrientationStimulusSelectionPanel, 'numeric');
            app.OrientFastMaxA.Value = 270;
            app.OrientFastTable = uitable(app.FastOrientationStimulusSelectionPanel);
            app.OrientFastTable.ColumnName = {'Stim n.'; 'Orientation'};
            app.OrientFastTable.RowName = {};
            app.OrientFastTable.ColumnEditable = [false true];
            app.ChoosestimEditFieldLabel_3 = uilabel(app.FastOrientationStimulusSelectionPanel);
            app.ChoosestimEditFieldLabel_3.Text = 'Choose stim.';
            app.OrientFastN = uieditfield(app.FastOrientationStimulusSelectionPanel, 'numeric');
            app.OrientFastN.Limits = [1 Inf];
            app.OrientFastN.RoundFractionalValues = 'on';
            app.OrientFastN.ValueDisplayFormat = '%.0f';
            app.OrientFastN.ValueChangedFcn = createCallbackFcn(app, @OrientFastNValueChanged, true);
            app.OrientFastN.Value = 1;
            app.CheckLumiBEsch = uieditfield(app.ContrastStimulationPanel, 'numeric');
            app.CheckLumiBEsch.Limits = [0 Inf];
            app.CheckLumiBEsch.Editable = 'off';
            app.CheckLumiBEsch.Tooltip = {'Luminance of the flash stimulus'};
            app.CheckLumiWEsch = uieditfield(app.ContrastStimulationPanel, 'numeric');
            app.CheckLumiWEsch.Limits = [0 Inf];
            app.CheckLumiWEsch.Editable = 'off';
            app.CheckLumiWEsch.Tooltip = {'Luminance of the flash stimulus'};
            app.CheckLumiWEsch.Value = 0.6;
            app.CheckLumiMeanEsch = uieditfield(app.ContrastStimulationPanel, 'numeric');
            app.CheckLumiMeanEsch.Limits = [0 Inf];
            app.CheckLumiMeanEsch.ValueDisplayFormat = '%5.3g';
            app.CheckLumiMeanEsch.Editable = 'off';
            app.CheckLumiMeanEsch.Tooltip = {'Luminance of the background'};
            app.CheckLumiMeanEsch.Value = 0.3;
            app.LabelLumiEsch_4 = uilabel(app.ContrastStimulationPanel);
            app.LabelLumiEsch_4.Text = '(0-1)';
            app.LabelLumiEsch_5 = uilabel(app.ContrastStimulationPanel);
            app.LabelLumiEsch_5.Text = '(0-1)';
            app.LabelLumiEsch_6 = uilabel(app.ContrastStimulationPanel);
            app.LabelLumiEsch_6.Text = '(0-1)';
            app.FastContrastStimulusSelectionPanel = uipanel(app.ContrastStimulationPanel);
            app.FastContrastStimulusSelectionPanel.Title = 'Fast Contrast Stimulus Selection';
            app.CheckFastTable = uitable(app.FastContrastStimulusSelectionPanel);
            app.CheckFastTable.ColumnName = {'Stim n.'; 'Contrast'; 'Mean L'};
            app.CheckFastTable.RowName = {};
            app.CheckFastTable.ColumnEditable = [false true true];
            app.ChoosestimEditFieldLabel_2 = uilabel(app.FastContrastStimulusSelectionPanel);
            app.ChoosestimEditFieldLabel_2.Text = 'Choose stim.';
            app.CheckFastN = uieditfield(app.FastContrastStimulusSelectionPanel, 'numeric');
            app.CheckFastN.Limits = [1 Inf];
            app.CheckFastN.RoundFractionalValues = 'on';
            app.CheckFastN.ValueDisplayFormat = '%.0f';
            app.CheckFastN.ValueChangedFcn = createCallbackFcn(app, @CheckFastNValueChanged, true);
            app.CheckFastN.Value = 1;
            app.CheckFastSaveTable = uibutton(app.FastContrastStimulusSelectionPanel, 'push');
            app.CheckFastSaveTable.ButtonPushedFcn = createCallbackFcn(app, @CheckFastSaveTableButtonPushed, true);
            app.CheckFastSaveTable.Text = 'save table';
            app.CheckFastLoadTable = uibutton(app.FastContrastStimulusSelectionPanel, 'push');
            app.CheckFastLoadTable.ButtonPushedFcn = createCallbackFcn(app, @CheckFastLoadTableButtonPushed, true);
            app.CheckFastLoadTable.Text = 'load table';
            app.generatestimuliCheckBtn = uibutton(app.FastContrastStimulusSelectionPanel, 'push');
            app.generatestimuliCheckBtn.ButtonPushedFcn = createCallbackFcn(app, @generatestimuliCheckBtnButtonPushed, true);
            app.generatestimuliCheckBtn.Text = {'generate stimuli'; 'table'};
            app.logspacedCheckCk = uicheckbox(app.FastContrastStimulusSelectionPanel);
            app.logspacedCheckCk.ValueChangedFcn = createCallbackFcn(app, @logspacedCheckCkValueChanged, true);
            app.logspacedCheckCk.Text = 'log spaced';
            app.logspacedCheckCk.Value = true;
            app.includezeroCheckCk = uicheckbox(app.FastContrastStimulusSelectionPanel);
            app.includezeroCheckCk.Text = 'include zero';
            app.includezeroCheckCk.Value = true;
            app.MinLEditFieldLabel_2 = uilabel(app.FastContrastStimulusSelectionPanel);
            app.MinLEditFieldLabel_2.Text = 'Min C';
            app.CheckFastMinC = uieditfield(app.FastContrastStimulusSelectionPanel, 'numeric');
            app.CheckFastMinC.Limits = [0 100];
            app.CheckFastMinC.ValueChangedFcn = createCallbackFcn(app, @CheckFastMinCValueChanged, true);
            app.CheckFastMinC.Value = 6;
            app.MaxLEditFieldLabel_2 = uilabel(app.FastContrastStimulusSelectionPanel);
            app.MaxLEditFieldLabel_2.Text = 'Max C';
            app.CheckFastMaxC = uieditfield(app.FastContrastStimulusSelectionPanel, 'numeric');
            app.CheckFastMaxC.Limits = [0 100];
            app.CheckFastMaxC.Value = 95;
            app.noftotstimLabel_2 = uilabel(app.FastContrastStimulusSelectionPanel);
            app.noftotstimLabel_2.Text = 'n. of tot stim';
            app.CheckFastTotN = uieditfield(app.FastContrastStimulusSelectionPanel, 'numeric');
            app.CheckFastTotN.Limits = [1 Inf];
            app.CheckFastTotN.RoundFractionalValues = 'on';
            app.CheckFastTotN.ValueChangedFcn = createCallbackFcn(app, @CheckFastTotNValueChanged, true);
            app.CheckFastTotN.Value = 6;
            app.MeanLEditFieldLabel = uilabel(app.FastContrastStimulusSelectionPanel);
            app.MeanLEditFieldLabel.Text = 'Mean L';
            app.CheckFastMeanL = uieditfield(app.FastContrastStimulusSelectionPanel, 'numeric');
            app.CheckFastMeanL.Limits = [0 Inf];
            app.CheckFastMeanL.Value = 0.6;
            app.FrequencycyclesDegLabel = uilabel(app.ContrastStimulationPanel);
            app.FrequencycyclesDegLabel.Tooltip = {'Spatial frequency expressed in cycles/Deg'};
            app.FrequencycyclesDegLabel.Text = 'Frequency (cycles/Deg):';
            app.spatialFrequency = uieditfield(app.ContrastStimulationPanel, 'numeric');
            app.spatialFrequency.Limits = [0 Inf];
            app.spatialFrequency.ValueDisplayFormat = '%5.3g';
            app.spatialFrequency.Tooltip = {'Spatial frequency expressed in cycles/Deg'};
            app.spatialFrequency.Value = 0.04;
            app.ExperimentlistTab = uitab(app.TabGroup);
            app.ExperimentlistTab.Title = 'Experiment list';
            app.ExperimentTable = uitable(app.ExperimentlistTab);
            app.ExperimentTable.ColumnName = {'Run n.'; 'Stimulus type'; 'Stimulus n.'; 'Notes'};
            app.ExperimentTable.RowName = {};
            app.ExperimentTable.ColumnEditable = [false true true true];
            app.ExperimentTable.CellSelectionCallback = createCallbackFcn(app, @ExperimentTableCellSelection, true);
            app.RandomizeexperimentPanel = uipanel(app.ExperimentlistTab);
            app.RandomizeexperimentPanel.Title = 'Randomize experiment';
            app.ExpListFlashCk = uicheckbox(app.RandomizeexperimentPanel);
            app.ExpListFlashCk.Text = 'Flash';
            app.ExpListFlashCk.Value = true;
            app.ExpListCheckCk = uicheckbox(app.RandomizeexperimentPanel);
            app.ExpListCheckCk.Text = 'Checkerboard (contrast)';
            app.ExpListCheckCk.Value = true;
            app.ExpListGratingsOrientCk = uicheckbox(app.RandomizeexperimentPanel);
            app.ExpListGratingsOrientCk.Text = 'Grating (orientation)';
            app.ExpListGratingsOrientCk.Value = true;
            app.RandomizeButton = uibutton(app.RandomizeexperimentPanel, 'push');
            app.RandomizeButton.ButtonPushedFcn = createCallbackFcn(app, @RandomizeButtonPushed, true);
            app.RandomizeButton.Text = 'Randomize';
            app.NumberofsessionsEditFieldLabel = uilabel(app.RandomizeexperimentPanel);
            app.NumberofsessionsEditFieldLabel.Text = 'Number of sessions';
            app.ExpListSessionN = uieditfield(app.RandomizeexperimentPanel, 'numeric');
            app.ExpListSessionN.Limits = [1 Inf];
            app.ExpListSessionN.Value = 2;
            app.ExpListGratingsContrastCk = uicheckbox(app.RandomizeexperimentPanel);
            app.ExpListGratingsContrastCk.Text = 'Grating (contrast)';
            app.ExpListGratingsContrastCk.Value = true;
            app.ExpListGo = uibutton(app.ExperimentlistTab, 'push');
            app.ExpListGo.ButtonPushedFcn = createCallbackFcn(app, @ExpListGoPushed, true);
            app.ExpListGo.Enable = 'off';
            app.ExpListGo.Text = 'Go!';
            app.ExpListAddRows = uibutton(app.ExperimentlistTab, 'push');
            app.ExpListAddRows.ButtonPushedFcn = createCallbackFcn(app, @ExpListAddRowsButtonPushed, true);
            app.ExpListAddRows.Text = 'add';
            app.ExpListDeleteRows = uibutton(app.ExperimentlistTab, 'push');
            app.ExpListDeleteRows.ButtonPushedFcn = createCallbackFcn(app, @ExpListDeleteRowsButtonPushed, true);
            app.ExpListDeleteRows.Enable = 'off';
            app.ExpListDeleteRows.Text = 'del';
            app.ExpMoveUp = uibutton(app.ExperimentlistTab, 'push');
            app.ExpMoveUp.ButtonPushedFcn = createCallbackFcn(app, @ExpMoveUpPushed, true);
            app.ExpMoveUp.Text = '↑';
            app.ExpMoveDown = uibutton(app.ExperimentlistTab, 'push');
            app.ExpMoveDown.ButtonPushedFcn = createCallbackFcn(app, @ExpMoveDownButtonPushed, true);
            app.ExpMoveDown.Text = '↓';
            app.CopytableButton = uibutton(app.ExperimentlistTab, 'push');
            app.CopytableButton.ButtonPushedFcn = createCallbackFcn(app, @CopytableButtonPushed, true);
            app.CopytableButton.Text = 'Copy table';
            app.CurrentrunnEditFieldLabel = uilabel(app.ExperimentlistTab);
            app.CurrentrunnEditFieldLabel.Text = 'Current run n.';
            app.ExpListCurrentNum = uieditfield(app.ExperimentlistTab, 'numeric');
            app.ExpListCurrentNum.Editable = 'off';
            app.RepeatStimBtn = uibutton(app.ExperimentlistTab, 'push');
            app.RepeatStimBtn.ButtonPushedFcn = createCallbackFcn(app, @RepeatStimBtnButtonPushed, true);
            app.RepeatStimBtn.Enable = 'off';
            app.RepeatStimBtn.Text = {'Repeat'; 'last'; 'stimulus'};
            app.NoffirstexpEditFieldLabel = uilabel(app.ExperimentlistTab);
            app.NoffirstexpEditFieldLabel.Text = 'N. of first exp';
            app.ExpListFirstNum = uieditfield(app.ExperimentlistTab, 'numeric');
            app.ExpListFirstNum.ValueChangedFcn = createCallbackFcn(app, @ExpListFirstNumValueChanged, true);
            app.ExpListFirstNum.Value = 1;
            app.PupilWhileStimulating = uicheckbox(app.ExperimentlistTab);
            app.PupilWhileStimulating.Text = 'Pupil Preview while stimulating';
            app.PupilTab = uitab(app.TabGroup);
            app.PupilTab.Title = 'Pupil';
            app.CameraPanel = uipanel(app.PupilTab);
            app.CameraPanel.Title = 'Camera Panel';
            app.RefreshDevicesButton = uibutton(app.CameraPanel, 'push');
            app.RefreshDevicesButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshDevicesButtonPushed, true);
            app.RefreshDevicesButton.Text = 'Refresh Devices';
            app.CameraDropDownLabel = uilabel(app.CameraPanel);
            app.CameraDropDownLabel.Text = 'Camera:';
            app.CameraDropDown = uidropdown(app.CameraPanel);
            app.CameraDropDown.Items = {};
            app.CameraDropDown.ValueChangedFcn = createCallbackFcn(app, @CameraDropDownValueChanged, true);
            app.CameraDropDown.Value = {};
            app.ImageSizeDropDownLabel = uilabel(app.CameraPanel);
            app.ImageSizeDropDownLabel.Text = 'Image Size:';
            app.ImageSizeDropDown = uidropdown(app.CameraPanel);
            app.ImageSizeDropDown.Items = {};
            app.ImageSizeDropDown.ValueChangedFcn = createCallbackFcn(app, @ImageSizeDropDownValueChanged, true);
            app.ImageSizeDropDown.Value = {};
            app.CameraUnconnectedLampLabel = uilabel(app.CameraPanel);
            app.CameraUnconnectedLampLabel.Text = 'Camera Unconnected';
            app.CameraUnconnectedLamp = uilamp(app.CameraPanel);
            app.PreviewPanel = uipanel(app.PupilTab);
            app.PreviewPanel.Title = 'Preview Panel';
            app.NumberofPointsinPreviewGraphEditFieldLabel = uilabel(app.PreviewPanel);
            app.NumberofPointsinPreviewGraphEditFieldLabel.Text = 'Number of Points in Preview Graph';
            app.NumberofPointsinPreviewGraphEditField = uieditfield(app.PreviewPanel, 'numeric');
            app.NumberofPointsinPreviewGraphEditField.Limits = [1 Inf];
            app.NumberofPointsinPreviewGraphEditField.RoundFractionalValues = 'on';
            app.NumberofPointsinPreviewGraphEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofPointsinPreviewGraphEditFieldValueChanged, true);
            app.NumberofPointsinPreviewGraphEditField.Value = 100;
            app.RatefpsEditFieldLabel = uilabel(app.PreviewPanel);
            app.RatefpsEditFieldLabel.Text = 'Rate (fps):';
            app.RatefpsEditField = uieditfield(app.PreviewPanel, 'numeric');
            app.RatefpsEditField.Limits = [0 Inf];
            app.RatefpsEditField.ValueChangedFcn = createCallbackFcn(app, @RatefpsEditFieldValueChanged, true);
            app.RatefpsEditField.Value = 0.5;
            app.StartPreviewButton = uibutton(app.PreviewPanel, 'push');
            app.StartPreviewButton.ButtonPushedFcn = createCallbackFcn(app, @StartPreviewButtonPushed, true);
            app.StartPreviewButton.Text = 'Start Preview';
            app.skipPupilPrevCk = uicheckbox(app.PreviewPanel);
            app.skipPupilPrevCk.Text = 'Skip pupil analysis in preview';
            app.ResetAxesButton = uibutton(app.PreviewPanel, 'push');
            app.ResetAxesButton.ButtonPushedFcn = createCallbackFcn(app, @ResetAxesButtonPushed, true);
            app.ResetAxesButton.Text = 'Reset Axes';
            app.Image1UIAxes = uiaxes(app.PupilTab);
            title(app.Image1UIAxes, 'Pupil Recognition')
            app.Image1UIAxes.XTick = [];
            app.Image1UIAxes.YTick = [];
            app.Image2UIAxes = uiaxes(app.PupilTab);
            title(app.Image2UIAxes, 'Binarized Image')
            app.Image2UIAxes.XTick = [];
            app.Image2UIAxes.YTick = [];
            app.GraphUIAxes = uiaxes(app.PupilTab);
            title(app.GraphUIAxes, 'Pupil Measurement')
            xlabel(app.GraphUIAxes, 'Time (s)')
            ylabel(app.GraphUIAxes, 'Pupil Dimension (px)')
            app.ThresholdSliderLabel = uilabel(app.PupilTab);
            app.ThresholdSliderLabel.Text = 'Threshold';
            app.ThresholdSlider = uislider(app.PupilTab);
            app.ThresholdSlider.Limits = [0 1];
            app.ThresholdSlider.Value = 0.5;
            app.AcquisitionPanel = uipanel(app.PupilTab);
            app.AcquisitionPanel.Title = 'Acquisition Panel';
            app.DurationsEditFieldLabel = uilabel(app.AcquisitionPanel);
            app.DurationsEditFieldLabel.Text = 'Duration (s):';
            app.DurationsEditField = uieditfield(app.AcquisitionPanel, 'numeric');
            app.DurationsEditField.ValueChangedFcn = createCallbackFcn(app, @DurationsEditFieldValueChanged, true);
            app.DurationsEditField.Value = 90;
            app.PupilStartAcquisitionButton = uibutton(app.AcquisitionPanel, 'push');
            app.PupilStartAcquisitionButton.ButtonPushedFcn = createCallbackFcn(app, @PupilStartAcquisitionButtonPushed, true);
            app.PupilStartAcquisitionButton.Text = 'START ACQUISITION';
            app.PreviewWhileAcquiringCheckBox = uicheckbox(app.AcquisitionPanel);
            app.PreviewWhileAcquiringCheckBox.Text = 'Preview While Acquiring';
            app.PreviewWhileAcquiringCheckBox.Value = true;
            app.SaveFolderEditFieldLabel = uilabel(app.AcquisitionPanel);
            app.SaveFolderEditFieldLabel.Text = 'Save Folder';
            app.SaveFolderEditField = uieditfield(app.AcquisitionPanel, 'text');
            app.SaveFolderEditField.Value = 'D:\Enrico\Documents\MATLAB\pupillometry20190408 12';
            app.FilenEditFieldLabel = uilabel(app.AcquisitionPanel);
            app.FilenEditFieldLabel.Text = 'File n.';
            app.OutputFileN = uieditfield(app.AcquisitionPanel, 'numeric');
            app.OutputFileN.Limits = [1 Inf];
            app.OutputFileN.RoundFractionalValues = 'on';
            app.OutputFileN.ValueDisplayFormat = '%.0f';
            app.OutputFileN.Value = 1;
            app.StatusEditFieldLabel = uilabel(app.AcquisitionPanel);
            app.StatusEditFieldLabel.Text = 'Status:';
            app.acqStatusTxt = uieditfield(app.AcquisitionPanel, 'text');
            app.acqStatusTxt.Editable = 'off';
            app.acqStatusTxt.Value = 'Not acquiring';
            app.skipPupilAcqCk = uicheckbox(app.AcquisitionPanel);
            app.skipPupilAcqCk.Text = 'Skip pupil analysis';
            app.skipPupilAcqCk.Value = true;
            app.acqLamp = uilamp(app.AcquisitionPanel);
            app.BusyStatusLampLabel = uilabel(app.EscherMasterFig);
            app.BusyStatusLampLabel.Text = 'Busy Status:';
            app.BusyStatusLamp = uilamp(app.EscherMasterFig);
            app.DeBusyButton = uibutton(app.EscherMasterFig, 'push');
            app.DeBusyButton.ButtonPushedFcn = createCallbackFcn(app, @DeBusyButtonPushed, true);
            app.DeBusyButton.Text = 'De-Busy';
            app.UDPConnectionLampLabel = uilabel(app.EscherMasterFig);
            app.UDPConnectionLampLabel.Text = 'UDP Connection:';
            app.UDPConnectionLamp = uilamp(app.EscherMasterFig);
            % Show the figure after all components are created
            app.EscherMasterFig.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EscherAppMaster

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EscherMasterFig)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EscherMasterFig)
        end
    end
end